classdef sprout_version_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BoundsTab                       matlab.ui.container.Tab
        SelectNewDataSetButton          matlab.ui.control.Button
        ContinueButton_pg1              matlab.ui.control.Button
        TextArea_23                     matlab.ui.control.TextArea
        ClearSelectionButton_2          matlab.ui.control.Button
        ConfirmAreaButton               matlab.ui.control.Button
        SelectAreaofInterestButton      matlab.ui.control.Button
        ZYViewXMaximumProjectionPanel   matlab.ui.container.Panel
        Axes_ZYMaxProj_1                matlab.ui.control.UIAxes
        ScalingPanel                    matlab.ui.container.Panel
        UpperBoundSpinner               matlab.ui.control.Spinner
        UpperBoundSpinnerLabel          matlab.ui.control.Label
        LowerBoundSpinner               matlab.ui.control.Spinner
        LowerBoundSpinnerLabel          matlab.ui.control.Label
        XZViewYMaximumProjectionPanel   matlab.ui.container.Panel
        Axes_XZMaxProj_1                matlab.ui.control.UIAxes
        overhead_1                      matlab.ui.control.TextArea
        zslider_1                       matlab.ui.control.TextArea
        ZvalueSlider                    matlab.ui.control.Slider
        ZvalueSliderLabel               matlab.ui.control.Label
        File_text_1                     matlab.ui.control.TextArea
        LoadDataSetButton               matlab.ui.control.Button
        XYProjectionsPanel              matlab.ui.container.Panel
        Axes_bounds_1                   matlab.ui.control.UIAxes
        Axes_RGB_1                      matlab.ui.control.UIAxes
        ThresholdTab                    matlab.ui.container.Tab
        ClearTotalPointsButton          matlab.ui.control.Button
        ConfirmTotalPointsButton        matlab.ui.control.Button
        LoadDatasetButton_2             matlab.ui.control.Button
        ThresholdAdjustmentScalingFactorPanel  matlab.ui.container.Panel
        TextArea_7                      matlab.ui.control.TextArea
        ScalingSpinner                  matlab.ui.control.Spinner
        ScalingSpinnerLabel             matlab.ui.control.Label
        FineThreshSliderLabel           matlab.ui.control.Label
        FineThreshSlider                matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        OriginalRGBThresholdedImageOverlayImagePanel  matlab.ui.container.Panel
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UITable_2                       matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        SelectedPointSpinner            matlab.ui.control.Spinner
        SelectedPointSpinnerLabel       matlab.ui.control.Label
        TotalThreshPointsSpinner        matlab.ui.control.Spinner
        TotalThreshPointsSpinnerLabel   matlab.ui.control.Label
        ThresholdConfirmationPanel      matlab.ui.container.Panel
        ConfirmandPlotButton            matlab.ui.control.Button
        FitTypeDropDown                 matlab.ui.control.DropDown
        FitTypeDropDownLabel            matlab.ui.control.Label
        TextArea_6                      matlab.ui.control.TextArea
        UIAxes4                         matlab.ui.control.UIAxes
        TextArea_4                      matlab.ui.control.TextArea
        ConfirmThresholdButton          matlab.ui.control.Button
        StackLayerDropDown              matlab.ui.control.DropDown
        StackLayerDropDownLabel         matlab.ui.control.Label
        NucleiAnalysisTab               matlab.ui.container.Tab
        ExportNucleiButton              matlab.ui.control.Button
        zslider_2                       matlab.ui.control.TextArea
        ZvalueSlider_2                  matlab.ui.control.Slider
        ZvalueSlider_2Label             matlab.ui.control.Label
        TextArea_24                     matlab.ui.control.TextArea
        AnalysisSummaryPanel            matlab.ui.container.Panel
        UITable2_2                      matlab.ui.control.Table
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        FilteredTablePanel              matlab.ui.container.Panel
        UITable3                        matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        ChooseDirectoryButton           matlab.ui.control.Button
        NucleiFilteringParametersPanel  matlab.ui.container.Panel
        UpperExtentLimEditField         matlab.ui.control.NumericEditField
        UpperExtentLimEditFieldLabel    matlab.ui.control.Label
        LowerExtentLimEditField         matlab.ui.control.NumericEditField
        LowerExtentLimEditFieldLabel    matlab.ui.control.Label
        UpperVolumeLimEditField         matlab.ui.control.NumericEditField
        UpperVolumeLimEditFieldLabel    matlab.ui.control.Label
        LowerVolumeLimEditField         matlab.ui.control.NumericEditField
        LowerVolumeLimEditFieldLabel    matlab.ui.control.Label
        AnalyzeDataButton               matlab.ui.control.Button
        NucleiConnectivityButtonGroup   matlab.ui.container.ButtonGroup
        connectedButton_3               matlab.ui.control.ToggleButton
        connectedButton_2               matlab.ui.control.ToggleButton
        connectedButton                 matlab.ui.control.ToggleButton
        ExportOriginalButton            matlab.ui.control.Button
        Axes_RGB_3                      matlab.ui.control.UIAxes
        ActinAnalysisTab                matlab.ui.container.Tab
        zslider_3                       matlab.ui.control.TextArea
        ZvalueSlider_3                  matlab.ui.control.Slider
        ZvalueSlider_3Label             matlab.ui.control.Label
        TextArea_25                     matlab.ui.control.TextArea
        AnalysisSummaryPanel_2          matlab.ui.container.Panel
        UITable2_4                      matlab.ui.control.Table
        UIAxes5_4                       matlab.ui.control.UIAxes
        UIAxes5_3                       matlab.ui.control.UIAxes
        FilteredTablePanel_2            matlab.ui.container.Panel
        UITable3_2                      matlab.ui.control.Table
        UITable2_3                      matlab.ui.control.Table
        ActinFilteringParametersPanel   matlab.ui.container.Panel
        UseMeanNucleiVolumeCheckBox     matlab.ui.control.CheckBox
        LowerVolumeLimEditField_2       matlab.ui.control.NumericEditField
        LowerVolumeLimEditField_2Label  matlab.ui.control.Label
        AnalyzeDataButton_2             matlab.ui.control.Button
        ActinConnectivityButtonGroup    matlab.ui.container.ButtonGroup
        connectedButton_6               matlab.ui.control.ToggleButton
        connectedButton_5               matlab.ui.control.ToggleButton
        connectedButton_4               matlab.ui.control.ToggleButton
        ExportActinButton               matlab.ui.control.Button
        Axes_RGB_4                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        %Bounds - Page 1
            %Initialization
            stackorg; %raw original stack imported
            stacktiff; %raw image after tiffreadvolume
            totalz_uncut; %total number of z layers
            zvalue_1; %selected z value - page 1
            layer_1; %selected layer - page 1
            stacktiff2disp_1; %stacktfiff2disp - page 1
            lowerthresh; %lowerthresh for viewing
            upperthresh; %upperthresh for viewing
            
            %rectangle selection
                %rectangle position
                ymin;
                zmin;
                y2;
                z2;
            RGBimage %RGB image page 1
            RGBimagestackXZ %RGB image XZ
            RGBimagestackZY %RGB image ZY
            roi %rectangle shape
            
            %viewing copies
            RGBcopy;
            todispcopy;
            XZcopy;
            
            %output
            stack_cut %cut stack based on user selection
        
        
       %Page 2 variables:
            tot_cutz; %total cut z 
            zvalue_2; %z value selection for second page
            compscale; %scaling for right plot
            numthreshval; %number of thresh points total
            
            zvaluevectR; % zvalue vector layer 1
            zvaluevectG; % zvalue vector layer 2
            threshvectR; %thresh vector layer 1
            threshvectG; %thresh vector layer 2
            
            stacknum;
            
            pointneededR;
            pointneededG;
            
            threshval;
            threshvalcoarse;
            threshvalfine;
            
            %linear fit
            Rfit;
            Gfit;
            
            %output
            threshstackcut;
         
            %Page 3
            time; 
            actin_mean; %Bool value, true - use nuclei average for actin volume min
            
                %Nuclei Analysis
                connectivity %Nuclei Connectivity
                parameters1 %Nuclei Filtering parameters
                nuccount %Number of Nuclei Found
                S1totaltable %Table of all Nuclei
                labeledstack1 %Labeled Nuclei stack
                nuc_anal_num %Nuclei Analysis Number
                current_data %Current data in upper right
                
                %Nuclei Filtering
                    lowvol1;
                    highvol1;
                    lowextent1; 
                    highextent1;
                    
                    RGB_labeled; %labeled bool stack mosaic
                    
                    %z slider and viewing
                    zvalue_3;
                    
                    %table selection
                    z_values_col;
                    tableindex;
                    
                    %table making
                    nuc_data;
                    col_data;
                    
                    %average nuclei vol
                    mean_nuc_vol;
                %Actin Analysis
                connectivity_actin %Actin Connectivity
                parameters1_actin %Actin Filtering parameters
                actin_count %Number of Actin Found
                S1totaltable_actin %Table of all actin
                labeledstack1_actin %Labeled Actin stack
                actin_anal_num %Actin Analysis Number
                current_data_actin %Current data in upper right
                
                %Actin Filtering
                    lowvol2;
                    
                    RGB_labeled_actin; %labeled bool stack mosaic
                    
                    %z slider and viewing
                    zvalue_4;
                    
                    %table selection
                    z_values_col_actin;
                    tableindex_actin;
                    
                    %table making
                    actin_data;
                    col_data_actin;
    end
    
    properties (Access = public)
        S1totalsort_output 
        labeledstack_output 
    end
    
    methods (Access = private)
        
        function settable(app, zvaluevectR, threshvectR, zvaluevectG, threshvectG)
            %Setting Table
           
           zvalRdisp = zvaluevectR';
           threRdisp = threshvectR';
           zvalGdisp = zvaluevectG';
           threGdisp = threshvectG';
          
           threRdisp(threRdisp == 0) = "NOT SET";
           threGdisp(threGdisp == 0) = "NOT SET";
         
          
%            combR = cat(2, zvalRdisp, threRdisp);
%            styleIndicesR = ismissing(combR);
%            [rowR,colR] = find(styleIndicesR);
%            
%            combG = cat(2, zvalGdisp, threGdisp);
%            styleIndicesG = ismissing(combG);
%            
%            [rowG,colG] = find(styleIndicesG);
           
           tablevalR = table(zvalRdisp, threRdisp);
           tablevalG = table(zvalGdisp, threGdisp);
           
           app.UITable.Data = tablevalR;
           app.UITable_2.Data = tablevalG;
           
%            styleR = uistyle('BackgroundColor', 'red');
%            styleG = uistyle('BackgroundColor', 'red');
%            
%            styleselect = uistyle('BackgroundColor', 'yellow');
%            addStyle(app.UITable, styleR, 'cell', [rowR,colR]);
%            addStyle(app.UITable_2, styleG, 'cell', [rowG,colG]); 
%            
%            if app.stacknum == 1 
%                addStyle(app.UITable, styleselect, 'cell', [app.pointneededR, 1]);
%                addStyle(app.UITable, styleselect, 'cell', [app.pointneededR, 2]);
%                app.UITable.StyleConfigurations
% 
%            elseif app.stacknum == 2
%                addStyle(app.UITable_2, styleselect, 'cell', [app.pointneededG, 1])
%                addStyle(app.UITable_2, styleselect, 'cell', [app.pointneededG, 2])
%            end
%        
        end
        
    function [nuccount, S1totalsort, labeledstack, RGB_stack_labeled] = nuclei_analysis_2(app, stacktiff_thrcut, threshstack, parameters1, connectivity)
    
                    % %% starter
                    % 
                    % [stackorg, pathname, index] = uigetfile('.tif', 'Open CELL Image');
                    % stacktiff = tiffreadVolume(stackorg);
                    % 
                    % stacktiff_thrcut = stacktiff(:,20:400,20:100, :);
                    % 
                    % parameters1 = [50,500,0.2,0.5]; %volumethreshmin1,volumethreshmax1, extentthreshmin1,extentthreshmax1
                    % parameters2 = [50]; %volumethreshmin2
                    % 
                    % threshstack(:,:,:,1) = imbinarize(stacktiff_thrcut(:,:,:,1)); %nuclei
                    % threshstack(:,:,:,2) = imbinarize(stacktiff_thrcut(:,:,:,2)); %actin
                    % 
                    % connectivity = 26;
                    
                    %% 

            %parameters for threshholding
            volumethreshmin1 = parameters1(1); 
            volumethreshmax1 = parameters1(2);
            extentthreshmin1 = parameters1(3);
            extentthreshmax1 = parameters1(4);
            
            % Hole Filling
        
            threshstack(:,:,:,1) = imfill(threshstack(:,:,:,1), 'holes');
            
            %Pre-analysis
        
            CC1 = bwconncomp(threshstack(:,:,:,1), app.connectivity);


            S1idlist = regionprops3(CC1,'VoxelList');
            S1vol = regionprops3(CC1, 'Volume', 'Extent'); %Generating table based on parameters
            tableS1 = table2array(S1vol);


            filtCC1 = false(size(threshstack(:,:,:,1)));
            nuccount = 0;
            
            %% Nuclei loop
            for i=1:(size(S1vol,1))
                if tableS1(i,1) >= volumethreshmin1 && tableS1(i,1) <= volumethreshmax1 && tableS1(i,2) >= extentthreshmin1 && tableS1(i,2) <= extentthreshmax1
                    specind = cell2mat(S1idlist{i,1});
                    y = specind(:,1);
                    x = specind(:,2);
                    z = specind(:,3);
                    for j = 1:(size(specind,1))
                      filtCC1(x(j),y(j),z(j)) = true;
                    end            
                    nuccount = nuccount + 1;
                    clear specind;
                end
            end


            %% New tables and initialization of nucleiRGBstack
            S1total = regionprops3(filtCC1,'Volume','Centroid','Orientation','PrincipalAxisLength','SurfaceArea','Extent','Orientation', 'EigenValues', 'EigenVectors',"EquivDiameter");
            
            S1total = splitvars(S1total); %split variables
            
            S1sorted = sortrows(S1total,4); %sort table
            
            namelist1 = linspace(1,size(S1total,1), size(S1total,1));
            namelist1 = permute(namelist1, [2,1]);
            tablelist1 = table(namelist1);
            tablelist1.Properties.VariableNames(1) = "Number";
            S1totalsort = [tablelist1 S1sorted];
        
            nucleiRGBstack(:,:,:,1) = filtCC1 * 255; %binarized layer
            
            nucleiRGBstack(:,:,:,2) = im2double(stacktiff_thrcut(:,:,:,2));
            
            nucleiblue = zeros(size(filtCC1));
            
            
                %% Mosiac Initialization
    
            bin_filt_CC1 = bwconncomp(filtCC1);
            label_CC1 = labelmatrix(bin_filt_CC1);

            size_filt_x = size(label_CC1,1);
            size_filt_y = size(label_CC1,2);
            size_filt_z = size(label_CC1,3);

            RGB_labeled = zeros(size_filt_x, size_filt_y, 3, size_filt_z);

            for z_size = 1:size(label_CC1,3)
                RGB_labeled(:,:,:,z_size) = label2rgb(label_CC1(:,:,z_size),'jet', 'k');
            end
            %% Identifying nuclei, setting them to 255 in blue stack
            nucloctable = [];
            nuccount = size(S1totalsort,1);
            for i = 1:nuccount
                x = round(S1totalsort{i,3});
                y = round(S1totalsort{i,4});
                z = round(S1totalsort{i,5});
                nucnum = S1totalsort{i,1};
        
        
                if x < 1
                    x = 1;
                end
                if y < 1
                    y = 1;
                end
                if z < 1
                    z = 1;
                end
                if x > size(filtCC1,2)
                    x = size(filtCC1,2);
                end
                if y > size(filtCC1,1)
                    y = size(filtCC1,1);
                end
                if z > size(filtCC1,3)
                    z = size(filtCC1,3);
                end
        
                nucleiblue(y,x,z) = 255;
        
                nucloctable = [nucloctable; nucnum, x, y, z];
            end
            nucleiRGBstack(:,:,:,3) = nucleiblue;
        
            nucstackperm = permute(nucleiRGBstack, [1, 2, 4, 3]);
            
            %% Labeling Found Nuclei
            totzval = size(nucstackperm, 4);
            zvalues = nucloctable(:,4);
            labeledstack = nucstackperm;
            RGB_stack_labeled = RGB_labeled;
            
            for zsel = 1:totzval
                stacksel = nucstackperm(:,:,:,zsel);
                RGBsel = RGB_labeled(:,:,:,zsel);
                zindexes = find(zvalues == zsel);
                if ~isempty(zindexes)
                    for i = 1:(size(zindexes,1))
                        specind = zindexes(i);
                        index = nucloctable(specind,1);
                        xval = nucloctable(specind,2);
                        yval = nucloctable(specind,3);
                        stacksel = insertText(stacksel,[xval,yval], index, 'Fontsize', 10, 'TextColor', 'white', 'BoxOpacity', 0);
                        RGBsel = insertText(RGBsel,[xval,yval], index, 'Fontsize', 10, 'TextColor', 'white', 'BoxOpacity', 0);
                        stacksel = insertMarker(stacksel, [xval,yval], 'Color', 'white', 'Size', 3);
                    end
                    labeledstack(:,:,:,zsel) = stacksel;
                    RGB_stack_labeled(:,:,:,zsel) = RGBsel;
                    clear stacksel;
                    clear RGBsel;
                end
            end
        
          %Plotting histograms
            %Volumes
              histogram(app.UIAxes5, tableS1(:,1), 50, 'BinWidth', 20); %raw volume
              hold(app.UIAxes5, 'on');
              histogram(app.UIAxes5, S1totalsort.Volume, 50, 'BinWidth', 20); %filtered volume     
              
              xline(app.UIAxes5, app.lowvol1);
              xline(app.UIAxes5, app.highvol1);
              
              legend(app.UIAxes5, 'Raw','Filtered', 'Min', 'Max');
              
              hold(app.UIAxes5, 'off');
              
            %Extent
              histogram(app.UIAxes5_2, tableS1(:,2)); %raw extent
              hold(app.UIAxes5_2, 'on');
              histogram(app.UIAxes5_2, S1totalsort.Extent); %filtered extent
              
              
              xline(app.UIAxes5_2, app.lowextent1);
              xline(app.UIAxes5_2, app.highextent1);
              
              legend(app.UIAxes5_2, 'Raw','Filtered', 'Min', 'Max');
              hold(app.UIAxes5_2, 'off');
            
            %Plotting Tables
            %Volume
                
                vol_stats_filt = datastats(S1totalsort.Volume);
                vol_filt = transpose(struct2array(vol_stats_filt));
                vol_filt_pt = [app.nuc_anal_num;vol_filt];
            %Extent
                
                ex_stats_filt = datastats(S1totalsort.Extent);
                ex_filt = transpose(struct2array(ex_stats_filt));
                ex_filt_pt = [app.nuc_anal_num;ex_filt];     
           
           app.mean_nuc_vol = vol_stats_filt.mean;
           
           initcolnames = {'Volume (Raw)', 'Volume (Filtered)', 'Extent (Raw)', 'Extent (Filtered)'};
           colnames = {'Volume (Filtered)', 'Extent (Filtered)'};
           
             if app.nuc_anal_num == 1
                vol_stats_raw = datastats(tableS1(:,1));            
                vol_raw = transpose(struct2array(vol_stats_raw));
                vol_raw_pt = [app.nuc_anal_num;vol_raw];
                
                ex_stats_raw = datastats(tableS1(:,2));            
                ex_raw = transpose(struct2array(ex_stats_raw));
                ex_raw_pt = [app.nuc_anal_num;ex_raw];
                
                 tabledata = horzcat(vol_raw_pt, vol_filt_pt, ex_raw_pt, ex_filt_pt);
                 app.UITable2_2.Data = tabledata;
                 app.UITable2_2.ColumnName = initcolnames;
                 app.col_data = initcolnames;
             else
                 
                 tabledata = horzcat(app.nuc_data, vol_filt_pt, ex_filt_pt);
                 colnames = horzcat(app.col_data, colnames);
                 app.UITable2_2.Data = tabledata;
                 app.UITable2_2.ColumnName = colnames;                 
                 app.col_data = colnames;
             end
             
            app.UITable2_2.RowName = {'Analysis Number', 'Num', 'Max', 'Min', 'Mean', 'Median', 'Range', 'Std'};
            
            app.nuc_data = tabledata;

    end 
    
    function [actincount, S2totalsort, labeledstack, RGB_stack_labeled] = actin_analysis(app, stacktiff_thrcut, threshstack, parameters1, connectivity)
    
                    % %% starter
                    % 
                    % [stackorg, pathname, index] = uigetfile('.tif', 'Open CELL Image');
                    % stacktiff = tiffreadVolume(stackorg);
                    % 
                    % stacktiff_thrcut = stacktiff(:,20:400,20:100, :);
                    % 
                    % parameters1 = [50,500,0.2,0.5]; %volumethreshmin1,volumethreshmax1, extentthreshmin1,extentthreshmax1
                    % parameters2 = [50]; %volumethreshmin2
                    % 
                    % threshstack(:,:,:,1) = imbinarize(stacktiff_thrcut(:,:,:,1)); %nuclei
                    % threshstack(:,:,:,2) = imbinarize(stacktiff_thrcut(:,:,:,2)); %actin
                    % 
                    % connectivity = 26;
                    
                    %% 

            %parameters for threshholding
            volumethreshmin1 = parameters1(1); 
            
            %Pre-analysis
        
            CC2 = bwconncomp(threshstack(:,:,:,2), connectivity);


            S2idlist = regionprops3(CC2,'VoxelList');
            S2vol = regionprops3(CC2, 'Volume', 'Extent'); %Generating table based on parameters
            tableS2 = table2array(S2vol);


            filtCC2 = false(size(threshstack(:,:,:,2)));
            actincount = 0;
            
            %% Actin loop
            for i=1:(size(S2vol,1))
                if tableS2(i,1) >= volumethreshmin1 
                    specind = cell2mat(S2idlist{i,1});
                    y = specind(:,1);
                    x = specind(:,2);
                    z = specind(:,3);
                    for j = 1:(size(specind,1))
                      filtCC2(x(j),y(j),z(j)) = true;
                    end            
                    actincount = actincount + 1;
                    clear specind;
                end
            end


            %% New tables and initialization of nucleiRGBstack
            S2total = regionprops3(filtCC2,'Volume','Centroid','Orientation','PrincipalAxisLength','SurfaceArea','Extent','Orientation', 'EigenValues', 'EigenVectors',"EquivDiameter");
            
            S2total = splitvars(S2total); %split variables
            
            S2sorted = sortrows(S2total,4); %sort table
            
            namelist2 = linspace(1,size(S2total,1), size(S2total,1));
            namelist2 = permute(namelist2, [2,1]);
            tablelist2 = table(namelist2);
            tablelist2.Properties.VariableNames(1) = "Number";
            S2totalsort = [tablelist2 S2sorted];
        
            actinRGBstack(:,:,:,1) = filtCC2 * 255; %binarized layer
            
            actinRGBstack(:,:,:,2) = im2double(stacktiff_thrcut(:,:,:,2)); %%Not sure about this
            
            actinblue = zeros(size(filtCC2));
            
            
                %% Mosiac Initialization
    
            bin_filt_CC2 = bwconncomp(filtCC2);
            label_CC2 = labelmatrix(bin_filt_CC2);

            size_filt_x = size(label_CC2,1);
            size_filt_y = size(label_CC2,2);
            size_filt_z = size(label_CC2,3);

            RGB_labeled = zeros(size_filt_x, size_filt_y, 3, size_filt_z);

            for z_size = 1:size(label_CC2,3)
                RGB_labeled(:,:,:,z_size) = label2rgb(label_CC2(:,:,z_size),'jet', 'k');
            end
            %% Identifying nuclei, setting them to 255 in blue stack
            actinloctable = [];
            actincount = size(S2totalsort,1);
            for i = 1:actincount
                x = round(S2totalsort{i,3});
                y = round(S2totalsort{i,4});
                z = round(S2totalsort{i,5});
                actinnum = S2totalsort{i,1};
        
        
                if x < 1
                    x = 1;
                end
                if y < 1
                    y = 1;
                end
                if z < 1
                    z = 1;
                end
                if x > size(filtCC2,2)
                    x = size(filtCC2,2);
                end
                if y > size(filtCC2,1)
                    y = size(filtCC2,1);
                end
                if z > size(filtCC2,3)
                    z = size(filtCC2,3);
                end
        
                actinblue(y,x,z) = 255;
        
                actinloctable = [actinloctable; actinnum, x, y, z];
            end
            actinRGBstack(:,:,:,3) = actinblue;
        
            actinstackperm = permute(actinRGBstack, [1, 2, 4, 3]);
            
            %% Labeling Found Actin
            totzval = size(actinstackperm, 4);
            zvalues = actinloctable(:,4);
            labeledstack = actinstackperm;
            RGB_stack_labeled = RGB_labeled;
            
            for zsel = 1:totzval
                stacksel = actinstackperm(:,:,:,zsel);
                RGBsel = RGB_labeled(:,:,:,zsel);
                zindexes = find(zvalues == zsel);
                if ~isempty(zindexes)
                    for i = 1:(size(zindexes,1))
                        specind = zindexes(i);
                        index = actinloctable(specind,1);
                        xval = actinloctable(specind,2);
                        yval = actinloctable(specind,3);
                        stacksel = insertText(stacksel,[xval,yval], index, 'Fontsize', 10, 'TextColor', 'white', 'BoxOpacity', 0);
                        RGBsel = insertText(RGBsel,[xval,yval], index, 'Fontsize', 10, 'TextColor', 'white', 'BoxOpacity', 0);
                        stacksel = insertMarker(stacksel, [xval,yval], 'Color', 'white', 'Size', 3);
                    end
                    labeledstack(:,:,:,zsel) = stacksel;
                    RGB_stack_labeled(:,:,:,zsel) = RGBsel;
                    clear stacksel;
                    clear RGBsel;
                end
            end
        
          %Plotting histograms
            %Volumes
              histogram(app.UIAxes5_3, tableS2(:,1), 'BinWidth', 200); %raw volume
              hold(app.UIAxes5_3, 'on');
              histogram(app.UIAxes5_3, S2totalsort.Volume, 'BinWidth', 200); %filtered volume     
              
              xline(app.UIAxes5_3, app.lowvol2);
              
              legend(app.UIAxes5_3, 'Raw','Filtered', 'Min', 'Max');
              
              hold(app.UIAxes5_3, 'off');
              
            %Extent
              histogram(app.UIAxes5_4, tableS2(:,2)); %raw extent
              hold(app.UIAxes5_4, 'on');
              histogram(app.UIAxes5_4, S2totalsort.Extent); %filtered extent
              
              
              legend(app.UIAxes5_4, 'Raw','Filtered', 'Min', 'Max');
              hold(app.UIAxes5_4, 'off');
            
            %Plotting Tables
            %Volume
                vol_stats_filt = datastats(S2totalsort.Volume);
                vol_filt = transpose(struct2array(vol_stats_filt));
                vol_filt_pt = [app.actin_anal_num;vol_filt];
            %Extent
                
                ex_stats_filt = datastats(S2totalsort.Extent);
                ex_filt = transpose(struct2array(ex_stats_filt));
                ex_filt_pt = [app.actin_anal_num;ex_filt];                
           
           initcolnames = {'Volume (Raw)', 'Volume (Filtered)', 'Extent (Raw)', 'Extent (Filtered)'};
           colnames = {'Volume (Filtered)', 'Extent (Filtered)'};
           
             if app.actin_anal_num == 1
                vol_stats_raw = datastats(tableS2(:,1));            
                vol_raw = transpose(struct2array(vol_stats_raw));
                vol_raw_pt = [app.actin_anal_num;vol_raw];
                
                ex_stats_raw = datastats(tableS2(:,2));            
                ex_raw = transpose(struct2array(ex_stats_raw));
                ex_raw_pt = [app.actin_anal_num;ex_raw];
                
                 tabledata = horzcat(vol_raw_pt, vol_filt_pt, ex_raw_pt, ex_filt_pt);
                 app.UITable2_4.Data = tabledata;
                 app.UITable2_4.ColumnName = initcolnames;
                 app.col_data_actin = initcolnames;
             else
                 
                 tabledata = horzcat(app.actin_data, vol_filt_pt, ex_filt_pt);
                 colnames = horzcat(app.col_data_actin, colnames);
                 app.UITable2_4.Data = tabledata;
                 app.UITable2_4.ColumnName = colnames;                 
                 app.col_data_actin = colnames;
             end
             
            app.UITable2_4.RowName = {'Analysis Number', 'Num', 'Max', 'Min', 'Mean', 'Median', 'Range', 'Std'};
            
            app.actin_data = tabledata;

    end 

end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.File_text_1.Value = 'Please Load Your Data Set';

            %1st Page initialization
                app.ContinueButton_pg1.Enable = 'off';
                app.SelectAreaofInterestButton.Enable = 'off';
                app.ConfirmAreaButton.Enable = 'off';
                app.ClearSelectionButton_2.Enable = 'off';
                app.LowerBoundSpinner.Enable = 'off';
                app.UpperBoundSpinner.Enable = 'off';
                app.ZvalueSlider.Enable = 'off';
                app.SelectNewDataSetButton.Enable = 'off';
                app.ScalingPanel.Enable = 'off';
                app.zslider_1.Enable = 'off';
                
            %2nd Page initializatino
                app.TotalThreshPointsSpinner.Enable = 'off';
                app.ConfirmTotalPointsButton.Enable = 'off';
                app.ClearTotalPointsButton.Enable = 'off';
                app.StackLayerDropDown.Enable = 'off';
                app.SelectedPointSpinner.Enable = 'off';
                app.ConfirmThresholdButton.Enable = 'off';
                app.FitTypeDropDown.Enable = 'off';
                app.ConfirmandPlotButton.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.FineThreshSlider.Enable = 'off';
                app.ScalingSpinner.Enable = 'off';
                
            %3rd page initialization
                app.ChooseDirectoryButton.Enable = 'off';
                app.AnalyzeDataButton.Enable = 'off';
                app.ExportOriginalButton.Enable = 'off';
                app.ExportNucleiButton.Enable = 'off';
                app.ZvalueSlider_2.Enable = 'off';
                app.zslider_2.Enable = 'off';
                
                app.connectivity = 6;
                app.nuc_anal_num = 1;
                app.zvalue_3 = 1;
                app.LowerVolumeLimEditField.Value = 50;
                app.UpperVolumeLimEditField.Value = 500;
                app.LowerExtentLimEditField.Value = 0.2; 
                app.UpperExtentLimEditField.Value = 0.5;
                
                
                app.lowvol1 = app.LowerVolumeLimEditField.Value;
                app.highvol1 = app.UpperVolumeLimEditField.Value;
                app.lowextent1 = app.LowerExtentLimEditField.Value; 
                app.highextent1 = app.UpperExtentLimEditField.Value;
                
                %Histogram initialization
                app.UIAxes5.XLim = [0 1000];
                app.UIAxes5.YLim = [0 200];
                
                app.UIAxes5_2.YLim = [0 1000];
                
            %4th page initialization
                app.AnalyzeDataButton_2.Enable = 'off';
                app.ExportActinButton.Enable = 'off';
                app.UseMeanNucleiVolumeCheckBox.Enable = 'off';
                app.connectivity_actin = 6;
                app.actin_anal_num = 1;
                app.zvalue_4 = 1;
                app.LowerVolumeLimEditField_2.Value = 50;
                
                app.lowvol2 = app.LowerVolumeLimEditField_2.Value;
                
                %Histogram initialization
                app.UIAxes5_3.XLim = [0 10000];
                app.UIAxes5_3.YLim = [0 200];
                
                app.UIAxes5_4.YLim = [0 1000];
        end

        % Button pushed function: LoadDataSetButton
        function LoadDataSetButtonPushed(app, event)
            [app.stackorg, pathname, index] = uigetfile('.tif', 'Open Data Set');
            if index == 0
                app.File_text_1.Value = "Error: Dataset Not Loaded";
                app.LoadDataSetButton.BackgroundColor = 'r'; %set color to red
            else
                app.LoadDataSetButton.BackgroundColor = 'g'; %set color to green
                cd(pathname);
                app.File_text_1.Value = "Dataset Loaded: " + app.stackorg + newline + "Directory: " + pathname;
                
                %Adjust visibility - page 1
                    app.LoadDataSetButton.Enable = 'off';
                    app.SelectAreaofInterestButton.Enable = 'on';
                    app.LowerBoundSpinner.Enable = 'on';
                    app.UpperBoundSpinner.Enable = 'on';
                    app.ZvalueSlider.Enable = 'on';                
                    app.SelectNewDataSetButton.Enable = 'on';
                    app.ScalingPanel.Enable = 'on';
                    app.zslider_1.Enable = 'on';
                    
                    app.stacktiff = tiffreadVolume(app.stackorg); %read in original image
                    app.totalz_uncut = size(app.stacktiff,3);
                
                %Initialization of stack parameters
                    app.zvalue_1 = 1;
                    app.layer_1 = 1;
                    
                %Initialization of threshold spinners
                    app.LowerBoundSpinner.Limits = [0 254];
                    app.UpperBoundSpinner.Limits = [1 255];
                    app.lowerthresh = 20;
                    app.upperthresh = 80;
                    app.LowerBoundSpinner.Value = app.lowerthresh;
                    app.UpperBoundSpinner.Value = app.upperthresh;
                    app.stacktiff2disp_1 = mat2gray(app.stacktiff(:,:,app.zvalue_1,app.layer_1), [app.lowerthresh, app.upperthresh]);

                    %resizing
                        size_im = size(app.stacktiff,1);
                        app.Axes_bounds_1.XLim = [0 size_im];
                        app.Axes_bounds_1.YLim = [0 size_im];
                        
                    imshow(app.stacktiff2disp_1, 'Parent', app.Axes_bounds_1); %display in XY bounds panel
                
                %Show RGB plot in XY Panel
                    R = app.stacktiff(:,:,app.zvalue_1,1);
                    G = app.stacktiff(:,:,app.zvalue_1,2);
                    B = app.stacktiff(:,:,app.zvalue_1,3);
                    app.RGBimage = cat(3, R, G, B);
  
                    %resizing
                        app.Axes_RGB_1.XLim = [0 size_im];
                        app.Axes_RGB_1.YLim = [0 size_im];
                    
                    imshow(app.RGBimage, 'Parent', app.Axes_RGB_1);
                    
                %XZ view, Y compressed (max proj)    
                    stacktiff_XZcopy = app.stacktiff;
                    Rstack_1 = stacktiff_XZcopy(:,:,:,1);
                    Gstack_1 = stacktiff_XZcopy(:,:,:,2);
                    Bstack_1 = stacktiff_XZcopy(:,:,:,3);
                    
                    RstackXZ_1 = permute(Rstack_1, [1 3 2]);
                    GstackXZ_1 = permute(Gstack_1, [1 3 2]);
                    BstackXZ_1 = permute(Bstack_1, [1 3 2]);
                    
                    RstackXZ_1 = max(RstackXZ_1, [], 3);
                    GstackXZ_1 = max(GstackXZ_1, [], 3);
                    BstackXZ_1 = max(BstackXZ_1, [], 3);
                
                    app.RGBimagestackXZ = cat(3, RstackXZ_1, GstackXZ_1, BstackXZ_1);
                    
                    %resizing
                        sizeXZ_x = size(app.RGBimagestackXZ,2);
                        sizeXZ_y = size(app.RGBimagestackXZ,1);
                        app.Axes_XZMaxProj_1.XLim = [0 sizeXZ_x];
                        app.Axes_XZMaxProj_1.YLim = [0 sizeXZ_y];
                        
                    imshow(app.RGBimagestackXZ, 'Parent', app.Axes_XZMaxProj_1); 
                %% ZY view, Y compressed (max proj):
                    RstackZY_1 = permute(Rstack_1, [3 2 1]);
                    GstackZY_1 = permute(Gstack_1, [3 2 1]);
                    BstackZY_1 = permute(Bstack_1, [3 2 1]);
                    
                    RstackZY_1 = max(RstackZY_1, [], 3);
                    GstackZY_1 = max(GstackZY_1, [], 3);
                    BstackZY_1 = max(BstackZY_1, [], 3);
                    
                    app.RGBimagestackZY = cat(3, RstackZY_1, GstackZY_1, BstackZY_1);
                    imshow(app.RGBimagestackZY, 'Parent', app.Axes_ZYMaxProj_1);
         
                %Display text boxes
                    app.zslider_1.Value = num2str(app.zvalue_1);
                    app.overhead_1.Value = "Total Z Layers: " + num2str(app.totalz_uncut);
                
            end
        end

        % Value changed function: ZvalueSlider
        function ZvalueSliderValueChanged(app, event)
            app.zvalue_1 = round(app.totalz_uncut*(app.ZvalueSlider.Value/100));

            
            %Showing right XY Projection plot
            app.stacktiff2disp_1 = mat2gray(app.stacktiff(:,:,app.zvalue_1,app.layer_1), [app.lowerthresh, app.upperthresh]);
            
            %adding markers
            app.todispcopy = app.stacktiff2disp_1;
            if ~isempty(app.ymin)
               app.todispcopy(:,app.ymin) = 255;
               app.todispcopy(:,app.y2) = 255; 
            end
            imshow(app.todispcopy, 'Parent', app.Axes_bounds_1);
            
            %Showing left XY Projection plot - RGB
            R = app.stacktiff(:,:,app.zvalue_1,1);
            G = app.stacktiff(:,:,app.zvalue_1,2);
            B = app.stacktiff(:,:,app.zvalue_1,3);
            app.RGBimage = cat(3, R, G, B);

            %adding markers
            app.RGBcopy = app.RGBimage;
            if ~isempty(app.ymin)
                app.RGBcopy(:,app.ymin,:) = 255;
                app.RGBcopy(:,app.y2,:) = 255;
            end
            
            imshow(app.RGBcopy, 'Parent', app.Axes_RGB_1);           
            
            
            %Showing XZ Plots
            app.XZcopy = app.RGBimagestackXZ;
            if ~isempty(app.ymin)
                app.XZcopy(:,app.zmin,:) = 255;
                app.XZcopy(:,app.z2,:) = 255;
            end
            app.XZcopy(:,app.zvalue_1,1) = 255;
            if ~isempty(app.ymin)
                if app.zmin == app.zvalue_1 || app.z2 == app.zvalue_1
                    app.XZcopy(:,app.zvalue_1,1) = 255;
                    app.XZcopy(:,app.zvalue_1,2) = 0;
                    app.XZcopy(:,app.zvalue_1,3) = 0;
                end
            end
            imshow(app.XZcopy, 'Parent', app.Axes_XZMaxProj_1);
            
            %Adding red line on ZY view
            ZYcopy = app.RGBimagestackZY;
            ZYcopy(app.zvalue_1,:,1) = 255;
            
            imshow(ZYcopy, 'Parent', app.Axes_ZYMaxProj_1);
            
            app.zslider_1.Value = num2str(app.zvalue_1);
                         
      
        end

        % Callback function
        function ConfirmLowerBoundButtonPushed(app, event)
            if app.lower_selected == 1
                app.overhead_1.Value = "LowerBound: " + app.lowerboundstr + " -- UpperBound: "+ app.upperboundstr +" -- Total Z Layers: " + num2str(app.totalz) + newline + "Lower Bound Value already entered, please clear selection before selecting a new value";
            elseif app.lower_selected == 0
                app.lowerbound = app.zvalue;
                app.lowerboundstr = num2str(app.lowerbound);
                app.overhead_1.Value = "LowerBound: " + app.lowerboundstr + " -- UpperBound: "+ app.upperboundstr +" -- Total Z Layers: " + num2str(app.totalz);
                
                app.stacktiff_YZcopy(:,:,(app.lowerbound-1):(app.lowerbound+1),1) = 255;
                app.stacktiff_YZcopy(:,:,app.lowerbound,2) = 0;
                app.stacktiff_YZcopy(:,:,app.lowerbound,3) = 0;
                
                app.R = app.stacktiff(:,:,app.zvalue,1);
                app.G = app.stacktiff(:,:,app.zvalue,2);
                app.B = app.stacktiff(:,:,app.zvalue,3);
                
    
                RGBimage = cat(3, app.R, app.G, app.B);
                imshow(RGBimage, 'Parent', app.Axes_RGB_1);
    
                Rstack = app.stacktiff_YZcopy(:,:,:,1);
                Gstack = app.stacktiff_YZcopy(:,:,:,2);
                Bstack = app.stacktiff_YZcopy(:,:,:,3);
                %% XZ view, Y compressed:
                RstackXZ = permute(Rstack, [1 3 2]);
                GstackXZ = permute(Gstack, [1 3 2]);
                BstackXZ = permute(Bstack, [1 3 2]);
                
                RstackmaxXZ = max(RstackXZ, [], 3);
                GstackmaxXZ = max(GstackXZ, [], 3);
                BstackmaxXZ = max(BstackXZ, [], 3);
                
                if app.zvalue == 1
                    RstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                    GstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                    BstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                elseif app.zvalue == app.totalz
                    RstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                    GstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                    BstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                else
                    RstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                    GstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                    BstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                end
                
    
                RGBimagestackXZ = cat(3, RstackmaxXZ, GstackmaxXZ, BstackmaxXZ);
                imshow(RGBimagestackXZ, 'Parent', app.Axes_XZMaxProj_1);
                
                %% ZY view, X compressed:
                RstackZY = permute(Rstack, [3 2 1]);
                GstackZY = permute(Gstack, [3 2 1]);
                BstackZY = permute(Bstack, [3 2 1]);
                
                RstackmaxZY = max(RstackZY, [], 3);
                GstackmaxZY = max(GstackZY, [], 3);
                BstackmaxZY = max(BstackZY, [], 3);
                
                if app.zvalue == 1
                    RstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                    GstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                    BstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                elseif app.zvalue == app.totalz
                    RstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                    GstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                    BstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                else
                    RstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255;
                    GstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255;
                    BstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255; 
                end
                
    
                RGBimagestackZY = cat(3, RstackmaxZY, GstackmaxZY, BstackmaxZY);
                imshow(RGBimagestackZY, 'Parent', app.Axes_ZYMaxProj_1);
                
                app.zslider_1.Value = num2str(app.zvalue);
                app.lower_selected = 1;
                app.ConfirmLowerBoundButton.BackgroundColor = 'g';
                
                %Do not show Continue Button Until Bounds Met:
            
                if app.upper_selected == 0 || app.lower_selected == 0
                    app.ContinueButton_3.Visible = 'off';
                else
                    app.ContinueButton_3.Visible = 'on';
                end
            end
        end

        % Callback function
        function ConfirmUpperBoundButtonPushed(app, event)
            if app.upper_selected == 1
                app.overhead_1.Value = "LowerBound: " + app.lowerboundstr + " -- UpperBound: "+ app.upperboundstr +" -- Total Z Layers: " + num2str(app.totalz) + newline + "Upper Bound Value already entered, please clear selection before selecting a new value";
            elseif app.upper_selected == 0
                app.upperbound = app.zvalue;
                app.upperboundstr = num2str(app.upperbound);
                app.overhead_1.Value = "LowerBound: " + app.lowerboundstr + " -- UpperBound: "+ app.upperboundstr +" -- Total Z Layers: " + num2str(app.totalz);
                
                app.stacktiff_YZcopy(:,:,(app.upperbound-1):(app.upperbound+1),1) = 255;
                app.stacktiff_YZcopy(:,:,app.upperbound,2) = 0;
                app.stacktiff_YZcopy(:,:,app.upperbound,3) = 0;
                
                app.R = app.stacktiff(:,:,app.zvalue,1);
                app.G = app.stacktiff(:,:,app.zvalue,2);
                app.B = app.stacktiff(:,:,app.zvalue,3);
                
    
                RGBimage = cat(3, app.R, app.G, app.B);
                imshow(RGBimage, 'Parent', app.Axes_RGB_1);
    
                Rstack = app.stacktiff_YZcopy(:,:,:,1);
                Gstack = app.stacktiff_YZcopy(:,:,:,2);
                Bstack = app.stacktiff_YZcopy(:,:,:,3);
                %% XZ view, Y compressed:
                RstackXZ = permute(Rstack, [1 3 2]);
                GstackXZ = permute(Gstack, [1 3 2]);
                BstackXZ = permute(Bstack, [1 3 2]);
                
                RstackmaxXZ = max(RstackXZ, [], 3);
                GstackmaxXZ = max(GstackXZ, [], 3);
                BstackmaxXZ = max(BstackXZ, [], 3);
                
                if app.zvalue == 1
                    RstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                    GstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                    BstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                elseif app.zvalue == app.totalz
                    RstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                    GstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                    BstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                else
                    RstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                    GstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                    BstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                end
                
    
                RGBimagestackXZ = cat(3, RstackmaxXZ, GstackmaxXZ, BstackmaxXZ);
                imshow(RGBimagestackXZ, 'Parent', app.Axes_XZMaxProj_1);
                
                %% ZY view, X compressed:
                RstackZY = permute(Rstack, [3 2 1]);
                GstackZY = permute(Gstack, [3 2 1]);
                BstackZY = permute(Bstack, [3 2 1]);
                
                RstackmaxZY = max(RstackZY, [], 3);
                GstackmaxZY = max(GstackZY, [], 3);
                BstackmaxZY = max(BstackZY, [], 3);
                
                if app.zvalue == 1
                    RstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                    GstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                    BstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                elseif app.zvalue == app.totalz
                    RstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                    GstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                    BstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                else
                    RstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255;
                    GstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255;
                    BstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255; 
                end
                
    
                RGBimagestackZY = cat(3, RstackmaxZY, GstackmaxZY, BstackmaxZY);
                imshow(RGBimagestackZY, 'Parent', app.Axes_ZYMaxProj_1);
                
                app.zslider_1.Value = num2str(app.zvalue);
                app.upper_selected = 1;
                app.ConfirmUpperBoundButton.BackgroundColor = 'g';
                
            %Do not show Continue Button Until Bounds Met:
            
                if app.upper_selected == 0 || app.lower_selected == 0
                    app.ContinueButton_3.Visible = 'off';
                else
                    app.ContinueButton_3.Visible = 'on';
                end                
            end
        end

        % Value changed function: zslider_1
        function zslider_1ValueChanged(app, event)
                if app.zslider_1.Value > app.zvalue
                    app.zslider_1.Value = app.totalz;
                elseif app.zslider_1.Value < app.zvalue 
                    app.zslider_1.Value = 1;
                elseif isinteger(app.zslider_1.Value) == 0
                    app.zslider_1.Value = round(app.zslider_1.Value);
                end
                app.zvalue = app.zslider_1.Value;
                
                app.ZvalueSlider.Value = (app.zvalue/app.totalz)*100;
                
                imshow(app.stacktiff(:,:,app.zvalue,app.layer), 'Parent', app.Axes_bounds_1);
                
                app.R = app.stacktiff(:,:,app.zvalue,1);
                app.G = app.stacktiff(:,:,app.zvalue,2);
                app.B = app.stacktiff(:,:,app.zvalue,3);
                
    
                RGBimage = cat(3, app.R, app.G, app.B);
                imshow(RGBimage, 'Parent', app.Axes_RGB_1);
                
                Rstack = app.stacktiff_YZcopy(:,:,:,1);
                Gstack = app.stacktiff_YZcopy(:,:,:,2);
                Bstack = app.stacktiff_YZcopy(:,:,:,3);
                %% XZ view, Y compressed:
                RstackXZ = permute(Rstack, [1 3 2]);
                GstackXZ = permute(Gstack, [1 3 2]);
                BstackXZ = permute(Bstack, [1 3 2]);
                
                RstackmaxXZ = max(RstackXZ, [], 3);
                GstackmaxXZ = max(GstackXZ, [], 3);
                BstackmaxXZ = max(BstackXZ, [], 3);
                
                if app.zvalue == 1
                    RstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                    GstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                    BstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                elseif app.zvalue == app.totalz
                    RstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                    GstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                    BstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                else
                    RstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                    GstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                    BstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                end
                
    
                RGBimagestackXZ = cat(3, RstackmaxXZ, GstackmaxXZ, BstackmaxXZ);
                imshow(RGBimagestackXZ, 'Parent', app.Axes_XZMaxProj_1);
                
                %% ZY view, X compressed:
                RstackZY = permute(Rstack, [3 2 1]);
                GstackZY = permute(Gstack, [3 2 1]);
                BstackZY = permute(Bstack, [3 2 1]);
                
                RstackmaxZY = max(RstackZY, [], 3);
                GstackmaxZY = max(GstackZY, [], 3);
                BstackmaxZY = max(BstackZY, [], 3);
                
                if app.zvalue == 1
                    RstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                    GstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                    BstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                elseif app.zvalue == app.totalz
                    RstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                    GstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                    BstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                else
                    RstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255;
                    GstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255;
                    BstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255; 
                end
                
    
                RGBimagestackZY = cat(3, RstackmaxZY, GstackmaxZY, BstackmaxZY);
                imshow(RGBimagestackZY, 'Parent', app.Axes_ZYMaxProj_1);
                
                app.zslider_1.Value = num2str(app.zvalue);
                
            %Do not show Continue Button Until Bounds Met:
            
            if app.upper_selected == 0 || app.lower_selected == 0
                app.ContinueButton_3.Visible = 'off';
            else
                app.ContinueButton_3.Visible = 'on';
            end                
        end

        % Callback function
        function ClearSelectionButtonPushed(app, event)
                app.upper_selected = 0;
                app.lower_selected = 0;
                app.lowerboundstr = "N/A";
                app.upperboundstr = "N/A";
                app.overhead_1.Value = "LowerBound: " + app.lowerboundstr + " -- UpperBound: "+ app.upperboundstr +" -- Total Z Layers: " + num2str(app.totalz) + newline + "Bound Values Cleared";
                
                app.ConfirmUpperBoundButton.BackgroundColor = [0.96,0.96,0.96];
                app.ConfirmLowerBoundButton.BackgroundColor = [0.96,0.96,0.96];
            imshow(app.stacktiff(:,:,app.zvalue,app.layer), 'Parent', app.Axes_bounds_1);
            app.R = app.stacktiff(:,:,app.zvalue,1);
            app.G = app.stacktiff(:,:,app.zvalue,2);
            app.B = app.stacktiff(:,:,app.zvalue,3);
            

            RGBimage = cat(3, app.R, app.G, app.B);
            imshow(RGBimage, 'Parent', app.Axes_RGB_1);
            
            app.stacktiff_YZcopy = app.stacktiff;
            
            Rstack = app.stacktiff_YZcopy(:,:,:,1);
            Gstack = app.stacktiff_YZcopy(:,:,:,2);
            Bstack = app.stacktiff_YZcopy(:,:,:,3);
            %% XZ view, Y compressed:
            RstackXZ = permute(Rstack, [1 3 2]);
            GstackXZ = permute(Gstack, [1 3 2]);
            BstackXZ = permute(Bstack, [1 3 2]);
            
            RstackmaxXZ = max(RstackXZ, [], 3);
            GstackmaxXZ = max(GstackXZ, [], 3);
            BstackmaxXZ = max(BstackXZ, [], 3);
            
            if app.zvalue == 1
                RstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                GstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
                BstackmaxXZ(:, (app.zvalue):(app.zvalue+2)) = 255;
            elseif app.zvalue == app.totalz
                RstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                GstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
                BstackmaxXZ(:, (app.zvalue-2):(app.zvalue)) = 255;
            else
                RstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                GstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
                BstackmaxXZ(:, (app.zvalue-1):(app.zvalue+1)) = 255;
            end
            

            RGBimagestackXZ = cat(3, RstackmaxXZ, GstackmaxXZ, BstackmaxXZ);
            imshow(RGBimagestackXZ, 'Parent', app.Axes_XZMaxProj_1);
            
            %% ZY view, X compressed:
            RstackZY = permute(Rstack, [3 2 1]);
            GstackZY = permute(Gstack, [3 2 1]);
            BstackZY = permute(Bstack, [3 2 1]);
            
            RstackmaxZY = max(RstackZY, [], 3);
            GstackmaxZY = max(GstackZY, [], 3);
            BstackmaxZY = max(BstackZY, [], 3);
            
            if app.zvalue == 1
                RstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                GstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
                BstackmaxZY((app.zvalue):(app.zvalue+2), :) = 255;
            elseif app.zvalue == app.totalz
                RstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                GstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
                BstackmaxZY((app.zvalue-2):(app.zvalue), :) = 255;
            else
                RstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255;
                GstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255;
                BstackmaxZY((app.zvalue-1):(app.zvalue+1), :) = 255; 
            end
            

            RGBimagestackZY = cat(3, RstackmaxZY, GstackmaxZY, BstackmaxZY);
            imshow(RGBimagestackZY, 'Parent', app.Axes_ZYMaxProj_1);
            
            %Do not show Continue Button Until Bounds Met:
            
            if app.upper_selected == 0 || app.lower_selected == 0
                app.ContinueButton_3.Visible = 'off';
            else
                app.ContinueButton_3.Visible = 'on';
            end
           
        end

        % Callback function
        function ContinueButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ThresholdTab;
        end

        % Button down function: ThresholdTab
        function ThresholdTabButtonDown(app, event)
                  
        end

        % Value changed function: StackLayerDropDown
        function StackLayerDropDownValueChanged(app, event)
            if app.StackLayerDropDown.Value == "Nuclei"
                app.stacknum = 1;
                app.SelectedPointSpinner.Value = app.pointneededR;
            elseif app.StackLayerDropDown.Value == "Cyto"
                app.stacknum = 2;
                app.SelectedPointSpinner.Value = app.pointneededG;
            end


           % Graphing                    
                %RGB Image LEFT
                R = app.stack_cut(:,:,app.zvalue_2,1);
                G = app.stack_cut(:,:,app.zvalue_2,2);
                B = app.stack_cut(:,:,app.zvalue_2,3);
                
                RGBimage = cat(3, R, G, B);
                imshow(RGBimage, 'Parent', app.UIAxes2);

                %Thresholded image MIDDLE
                app.threshval = graythresh(app.stack_cut(:,:,app.zvalue_2, app.stacknum));
                threshlayer = imbinarize(app.stack_cut(:,:,app.zvalue_2, app.stacknum), app.threshval);
               
                imshow(threshlayer, 'Parent', app.UIAxes2_2);
                
                %Composite Image RIGHT
                comp = uint8(zeros(size(app.stack_cut(:,:,app.zvalue_2,:))));
                comp = permute(comp, [1 2 4 3]);
                comp = comp(:,:,:,1);
                comp(:,:,1) = app.stack_cut(:,:,app.zvalue_2,app.stacknum)*app.compscale;
                comp(:,:,2) = threshlayer*255;
                
                imshow(comp, 'Parent', app.UIAxes2_3);
                
        %Displaying thesh on sliders
            app.ThresholdSlider.Value = app.threshval;
            app.threshvalcoarse = app.threshval;
            app.TextArea_7.Value = num2str(app.threshval);   
            
        %Setting Table
         settable(app, app.zvaluevectR, app.threshvectR, app.zvaluevectG, app.threshvectG) 
        end

        % Value changed function: TotalThreshPointsSpinner
        function TotalThreshPointsSpinnerValueChanged(app, event)
        app.numthreshval = app.TotalThreshPointsSpinner.Value;
            %Setting zvalue vectors
           
               %zvaluevectR
               app.zvaluevectR = [1];
               valR = round(app.tot_cutz/(app.numthreshval - 1));
               
               for i = 1:(app.numthreshval - 1)
                   app.zvaluevectR = [app.zvaluevectR, (valR*i)];
               end
               if app.zvaluevectR(app.numthreshval) > app.tot_cutz
                   app.zvaluevectR(app.numthreshval) = app.tot_cutz;
               end
               
               %zvaluevectG
               app.zvaluevectG = app.zvaluevectR;
            
           %Initializing thresh vectors
               app.threshvectR = zeros(1, app.numthreshval);
               app.threshvectG = zeros(1, app.numthreshval);
            
           %Initializing Stack Layer
               app.stacknum = 1;
      
                 %Setting Table
                settable(app, app.zvaluevectR, app.threshvectR, app.zvaluevectG, app.threshvectG)
        end

        % Value changed function: SelectedPointSpinner
        function SelectedPointSpinnerValueChanged(app, event)
          if app.stacknum == 1  
            app.pointneededR = app.SelectedPointSpinner.Value;
            app.TextArea_4.Value = "Diplaying Point " + num2str(app.pointneededR) + " of " + num2str(app.numthreshval)+ ". Please Confirm Threshold";
          elseif app.stacknum == 2
            app.pointneededG = app.SelectedPointSpinner.Value;
            app.TextArea_4.Value = "Diplaying Point " + num2str(app.pointneededG) + " of " + num2str(app.numthreshval)+ ". Please Confirm Threshold";  
          end
        
        %Selecting specific z value
            if app.stacknum == 1
                app.zvalue_2 = app.zvaluevectR(app.pointneededR);

            elseif app.stacknum == 2 
                app.zvalue_2 = app.zvaluevectG(app.pointneededG);
            end
            
         % Graphing                    
                %RGB Image LEFT
                R = app.stack_cut(:,:,app.zvalue_2,1);
                G = app.stack_cut(:,:,app.zvalue_2,2);
                B = app.stack_cut(:,:,app.zvalue_2,3);
                
                RGBimage = cat(3, R, G, B);
                imshow(RGBimage, 'Parent', app.UIAxes2);

                %Thresholded image MIDDLE
                app.threshval = graythresh(app.stack_cut(:,:,app.zvalue_2, app.stacknum));
                threshlayer = imbinarize(app.stack_cut(:,:,app.zvalue_2, app.stacknum), app.threshval);
                    
                imshow(threshlayer, 'Parent', app.UIAxes2_2);
                
                %Composite Image RIGHT
                comp = uint8(zeros(size(app.stack_cut(:,:,app.zvalue_2,:))));
                comp = permute(comp, [1 2 4 3]);
                comp = comp(:,:,:,1);
                comp(:,:,1) = app.stack_cut(:,:,app.zvalue_2,app.stacknum)*app.compscale;
                comp(:,:,2) = threshlayer*255;
                
                imshow(comp, 'Parent', app.UIAxes2_3);  
                
        %Displaying thesh on sliders
            app.ThresholdSlider.Value = app.threshval;
            app.threshvalcoarse = app.threshval;
            app.TextArea_7.Value = num2str(app.threshval); 
                
        %Setting Table
         settable(app, app.zvaluevectR, app.threshvectR, app.zvaluevectG, app.threshvectG) 
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            app.threshvalcoarse = app.ThresholdSlider.Value;
            app.threshval = app.threshvalcoarse + app.threshvalfine;
            app.TextArea_7.Value = num2str(app.threshval);

        % Graphing                    
                %RGB Image LEFT
                R = app.stack_cut(:,:,app.zvalue_2,1);
                G = app.stack_cut(:,:,app.zvalue_2,2);
                B = app.stack_cut(:,:,app.zvalue_2,3);
                
                RGBimage = cat(3, R, G, B);
                imshow(RGBimage, 'Parent', app.UIAxes2);

                %Thresholded image MIDDLE
                threshlayer = imbinarize(app.stack_cut(:,:,app.zvalue_2, app.stacknum), app.threshval);
                    
                imshow(threshlayer, 'Parent', app.UIAxes2_2);
                
                %Composite Image RIGHT
                comp = uint8(zeros(size(app.stack_cut(:,:,app.zvalue_2,:))));
                comp = permute(comp, [1 2 4 3]);
                comp = comp(:,:,:,1);
                comp(:,:,1) = app.stack_cut(:,:,app.zvalue_2,app.stacknum)*app.compscale;
                comp(:,:,2) = threshlayer*255;
                
                imshow(comp, 'Parent', app.UIAxes2_3);
                
        %Setting Table
         settable(app, app.zvaluevectR, app.threshvectR, app.zvaluevectG, app.threshvectG) 
        end

        % Value changed function: FineThreshSlider
        function FineThreshSliderValueChanged(app, event)
            app.threshvalfine = app.FineThreshSlider.Value;
            app.threshval = app.threshvalcoarse + app.threshvalfine;
            app.TextArea_7.Value = num2str(app.threshval);

        % Graphing                    
                %RGB Image LEFT
                R = app.stack_cut(:,:,app.zvalue_2,1);
                G = app.stack_cut(:,:,app.zvalue_2,2);
                B = app.stack_cut(:,:,app.zvalue_2,3);
                
                RGBimage = cat(3, R, G, B);
                imshow(RGBimage, 'Parent', app.UIAxes2);

                %Thresholded image MIDDLE
                threshlayer = imbinarize(app.stack_cut(:,:,app.zvalue_2, app.stacknum), app.threshval);
                    
                imshow(threshlayer, 'Parent', app.UIAxes2_2);
                
                %Composite Image RIGHT
                comp = uint8(zeros(size(app.stack_cut(:,:,app.zvalue_2,:))));
                comp = permute(comp, [1 2 4 3]);
                comp = comp(:,:,:,1);
                comp(:,:,1) = app.stack_cut(:,:,app.zvalue_2,app.stacknum)*app.compscale;
                comp(:,:,2) = threshlayer*255;
                
                imshow(comp, 'Parent', app.UIAxes2_3);
                
        %Setting Table
         settable(app, app.zvaluevectR, app.threshvectR, app.zvaluevectG, app.threshvectG)            
        end

        % Button pushed function: ConfirmThresholdButton
        function ConfirmThresholdButtonPushed(app, event)
            if app.stacknum == 1
                app.threshvectR(app.pointneededR) = app.threshval;
                if app.pointneededR < (app.numthreshval - 1)
                    app.pointneededR = app.pointneededR + 1;
                    app.SelectedPointSpinner.Value = app.pointneededR;
                    app.TextArea_4.Value = "Diplaying Point " + num2str(app.pointneededR) + " of " + num2str(app.numthreshval)+ ". Please Confirm Threshold";
                    app.zvalue_2 = app.zvaluevectR(app.pointneededR);
                else
                    if any(app.threshvectR == 0)
                        app.pointneededR = find(~app.threshvectR);
                        app.SelectedPointSpinner.Value = app.pointneededR;
                        app.TextArea_4.Value = "Diplaying Point " + num2str(app.pointneededR) + " of " + num2str(app.numthreshval)+ ". Please Confirm Threshold";
                        app.zvalue_2 = app.zvaluevectR(app.pointneededR);
                    elseif any(app.threshvectG == 0)
                        app.stacknum = 2;
                        app.pointneededG = 1;
                        app.SelectedPointSpinner.Value = app.pointneededG;
                        app.StackLayerDropDown.Value = "Cyto";
                        app.SelectedPointSpinner.Limits = [1, app.numthreshval];
                        app.TotalThreshPointsSpinner.Value = app.numthreshval;
                       
                        app.TextArea_4.Value = "Diplaying Point " + num2str(app.pointneededG) + " of " + num2str(app.numthreshval)+ ". Please Confirm Threshold";
                        app.zvalue_2 = app.zvaluevectG(app.pointneededG);
                    else
                        app.ContinueButton_3.Enable = 'on';
                        app.ConfirmandPlotButton.Enable = 'on';
                    end
                end
                
            elseif app.stacknum == 2
                app.threshvectG(app.pointneededG) = app.threshval;
                if app.pointneededG < (app.numthreshval - 1)
                    app.pointneededG = app.pointneededG + 1;
                    app.SelectedPointSpinner.Value = app.pointneededG;
                    app.TextArea_4.Value = "Diplaying Point " + num2str(app.pointneededG) + " of " + num2str(app.numthreshval)+ ". Please Confirm Threshold";
                    app.zvalue_2 = app.zvaluevectG(app.pointneededG);
                else
                    if any(app.threshvectG == 0)
                        app.pointneededG = find(~app.threshvectG);
                        app.SelectedPointSpinner.Value = app.pointneededG;
                        
                        app.TextArea_4.Value = "Diplaying Point " + num2str(app.pointneededG) + " of " + num2str(app.numthreshval)+ ". Please Confirm Threshold";
                        app.zvalue_2 = app.zvaluevectG(app.pointneededG);
                    elseif any(app.threshvectR == 0)
                        app.stacknum = 1;
                        app.pointneededR = 1;
                        app.SelectedPointSpinner.Value = app.pointneededR;
                        app.StackLayerDropDown.Value = "Nuclei";
                        app.SelectedPointSpinner.Limits = [1, app.numthreshval];
                        app.TotalThreshPointsSpinner.Value = app.numthreshval;
                        
                        app.TextArea_4.Value = "Diplaying Point " + num2str(app.pointneededR) + " of " + num2str(app.numthreshval)+ ". Please Confirm Threshold";
                        app.zvalue_2 = app.zvaluevectR(app.pointneededR);
                    else
                        app.ConfirmandPlotButton.Enable = 'on';
                    end
                end
            end    
        % Graphing                    
                %RGB Image LEFT
                R = app.stack_cut(:,:,app.zvalue_2,1);
                G = app.stack_cut(:,:,app.zvalue_2,2);
                B = app.stack_cut(:,:,app.zvalue_2,3);
                
                RGBimage = cat(3, R, G, B);
                imshow(RGBimage, 'Parent', app.UIAxes2);

                %Thresholded image MIDDLE
                
                app.threshval = graythresh(app.stack_cut(:,:,app.zvalue_2, app.stacknum));
                threshlayer = imbinarize(app.stack_cut(:,:,app.zvalue_2, app.stacknum), app.threshval);
                    
                imshow(threshlayer, 'Parent', app.UIAxes2_2);
                
                %Composite Image RIGHT
                comp = uint8(zeros(size(app.stack_cut(:,:,app.zvalue_2,:))));
                comp = permute(comp, [1 2 4 3]);
                comp = comp(:,:,:,1);
                comp(:,:,1) = app.stack_cut(:,:,app.zvalue_2,app.stacknum)*app.compscale;
                comp(:,:,2) = threshlayer*255;
                
                imshow(comp, 'Parent', app.UIAxes2_3);
                
            %Displaying thesh on sliders
            app.ThresholdSlider.Value = app.threshval;
            app.threshvalcoarse = app.threshval;
            app.TextArea_7.Value = num2str(app.threshval);
            
            %Setting Table
             settable(app, app.zvaluevectR, app.threshvectR, app.zvaluevectG, app.threshvectG) 
        end

        % Button down function: Axes_ZYMaxProj_1
        function Axes_ZYMaxProj_1ButtonDown(app, event)
            
        end

        % Button down function: Axes_RGB_1
        function Axes_RGB_1ButtonDown(app, event)
            
        end

        % Value changed function: File_text_1
        function File_text_1ValueChanged(app, event)
            value = app.File_text_1.Value;
            
        end

        % Callback function
        function LowerBoundThreshSliderValueChanged(app, event)
                app.lowerthresh = round(app.LowerBoundThreshSlider.Value);
                if app.lowerthresh > app.upperthresh
                    app.upperthresh = app.lowerthresh + 1;
                    app.UpperBoundThreshSlider.Value = app.upperthresh;
                end
                app.TextArea_9.Value = num2str(app.lowerthresh);
                app.TextArea_8.Value = num2str(app.upperthresh);
                
                stacktiff2disp = mat2gray(app.stacktiff(:,:,app.zvalue,app.layer), [app.lowerthresh, app.upperthresh]);
         
                imshow(stacktiff2disp, 'Parent', app.Axes_bounds_1);
        end

        % Callback function
        function UpperBoundThreshSliderValueChanged(app, event)
                app.upperthresh = round(app.UpperBoundThreshSlider.Value);
                if app.lowerthresh > app.upperthresh
                    app.lowerthresh = app.upperthresh - 1;
                    app.LowerBoundThreshSlider.Value = app.lowerthresh;
                end
                app.TextArea_9.Value = num2str(app.lowerthresh);
                app.TextArea_8.Value = num2str(app.upperthresh);
                
                stacktiff2disp = mat2gray(app.stacktiff(:,:,app.zvalue,app.layer), [app.lowerthresh, app.upperthresh]);
         
                imshow(stacktiff2disp, 'Parent', app.Axes_bounds_1);
        end

        % Callback function
        function ScalingFactorSliderValueChanged(app, event)
            app.compscale = app.ScalingFactorSlider.Value;
            app.TextArea_10.Value = num2str(app.compscale);
            
        %IMshow next point
        
            if app.stacknum == 1
                app.zvalue = app.zvaluevectR(app.pointneededR);                
                threshlayer = imbinarize(app.stack_cut(:,:,app.zvalue, app.stacknum), app.threshval);
                imshow(threshlayer, 'Parent', app.UIAxes2_2);
                
                %Creating the composite image
                app.comp = uint8(zeros(512, 512, 3));
                app.comp(:,:,1) = app.stack_cut(:,:,app.zvalue,app.stacknum)*app.compscale;
                app.comp(:,:,2) = threshlayer*255;
                imshow(app.comp, 'Parent', app.UIAxes2_3);
                
      
            elseif app.stacknum == 2
                
                app.zvalue = app.zvaluevectG(app.pointneededG);
                threshlayer = imbinarize(app.stack_cut(:,:,app.zvalue, app.stacknum), app.threshval);
                imshow(threshlayer, 'Parent', app.UIAxes2_2);
                
                %Creating the composite image

                app.comp = uint8(zeros(512, 512, 3));
                app.comp(:,:,1) = app.stack_cut(:,:,app.zvalue,app.stacknum)*app.compscale;
                app.comp(:,:,2) = threshlayer*255;
                imshow(app.comp, 'Parent', app.UIAxes2_3)
            end            
            
        end

        % Button pushed function: ConfirmandPlotButton
        function ConfirmandPlotButtonPushed(app, event)
            app.ConfirmandPlotButton.BackgroundColor = 'y';
            
            %Plotting extrapolation
                vectR = app.zvaluevectR;
                threshR = app.threshvectR;
                vectG = app.zvaluevectG;
                threshG = app.threshvectG;
                plot(app.UIAxes4, vectR, threshR, '--or');
                hold(app.UIAxes4, 'on');
                plot(app.UIAxes4, vectG, threshG, '--og');
                hold(app.UIAxes4, 'on');
            
            x = linspace(1, app.tot_cutz, 1000);
            
            %Polyfit
            app.Rfit = polyfit(vectR, threshR, 1);
            app.Gfit = polyfit(vectG, threshG, 1);
            
            y_est_R = polyval(app.Rfit, x);
            y_est_G = polyval(app.Gfit, x);
            
            plot(app.UIAxes4, x, y_est_R, 'r', 'LineWidth', 2);
            hold(app.UIAxes4, 'on');
            plot(app.UIAxes4, x, y_est_G, 'g', 'LineWidth', 2);
            hold(app.UIAxes4, 'off');
            
            %Textbox:
            app.TextArea_6.Value = "Fit for Stack 1 (R): y = " + num2str(app.Rfit(1)) + "*x + " + num2str(app.Rfit(2)) + newline + "Fit for Stack 2 (G): y = " + num2str(app.Gfit(1)) + "*x + " + num2str(app.Gfit(2));
            
            %Extrapolate
             for layersel = 1:2
                  for z = 1:app.tot_cutz
                      if layersel == 1
                          threshvalue = app.Rfit(1) * z + app.Rfit(2);
                      elseif layersel == 2
                          threshvalue = app.Gfit(1) * z + app.Gfit(2);
                      end
                      app.threshstackcut(:,:,z,layersel) = imbinarize(app.stack_cut(:, :, z, layersel), threshvalue);
                  end         
             end
             
             
             %% Hole filling

                %Filling actin holes
                actin_stack = app.threshstackcut(:,:,:,2);
                
                %z values
                actin_stack(:,:,1) = imfill(actin_stack(:,:,1), 'holes');
                actin_stack(:,:,size(actin_stack,3)) = imfill(actin_stack(:,:,size(actin_stack,3)), 'holes');
                
                % y values
                actin_stack = permute(actin_stack, [3 2 1]);
                
                actin_stack(:,:,1) = imfill(actin_stack(:,:,1), 'holes');
                actin_stack(:,:,size(actin_stack,3)) = imfill(actin_stack(:,:,size(actin_stack,3)), 'holes');
                
                % x values
                actin_stack = permute(actin_stack, [1 3 2]);
                
                actin_stack(:,:,1) = imfill(actin_stack(:,:,1), 'holes');
                actin_stack(:,:,size(actin_stack,3)) = imfill(actin_stack(:,:,size(actin_stack,3)), 'holes');
                
                %reshape
                
                actin_stack = permute(actin_stack, [2 3 1]);
                app.threshstackcut(:,:,:,2) = actin_stack;
            
          %adjust visibility on next page
            app.ChooseDirectoryButton.Enable = 'on';
            
            
            app.ConfirmandPlotButton.BackgroundColor = 'g';
        end

        % Callback function
        function LoadDatasetButtonPushed(app, event)
          
          %Initalize filtering
          app.lowvol1 = 50;
          app.highvol1 = 500;
          app.lowextent1 = 0.2;
          app.highextent1 = 0.5;
          app.lowvol2 = 50;


          app.LowerVolumeLimEditField.Value = app.lowvol1;
          app.UpperVolumeLimEditField.Value = app.highvol1;
          app.LowerExtentLimEditField.Value = app.lowextent1;
          app.UpperExtentLimEditField.Value = app.highextent1;
          app.LowerVolumeLimEditField_2.Value = app.lowvol2;
          
          app.DrawRegionofInterestButton.Enable = 'on';
          app.DrawRegionofInterestButton.Visible = 'on';
          app.connectivity = 6;
          
          %Loading Maximum Projection
             %% ZY view, X compressed:
            stacktiff_YZcopy_cut = app.stack_cut;
            Rstack = stacktiff_YZcopy_cut(:,:,:,1);
            Gstack = stacktiff_YZcopy_cut(:,:,:,2);
            Bstack = stacktiff_YZcopy_cut(:,:,:,3);
            
            RstackZY = permute(Rstack, [3 2 1]);
            GstackZY = permute(Gstack, [3 2 1]);
            BstackZY = permute(Bstack, [3 2 1]);
            
            RstackmaxZY = max(RstackZY, [], 3);
            GstackmaxZY = max(GstackZY, [], 3);
            BstackmaxZY = max(BstackZY, [], 3);
            
            RGBimagestackZY = cat(3, RstackmaxZY, GstackmaxZY, BstackmaxZY);
            
            %Change size of app.UIAxes2_4:
            app.RGBImsize = size(RGBimagestackZY);
            app.UIAxes2_4.XLim = [1, app.RGBImsize(2)];
            app.UIAxes2_4.YLim = [1, app.RGBImsize(1)];
            imshow(RGBimagestackZY, 'Parent', app.UIAxes2_4);
            
            
        end

        % Callback function
        function DrawRegionofInterestButtonPushed(app, event)
                app.roi = drawrectangle(app.UIAxes2_4);
                app.position = app.roi.Position;
                app.TextArea_11.Value = mat2str(app.position);
                addlistener(app.roi,'ROIMoved',@allevents);
                
                function allevents(src,evt)
                    evname = evt.EventName;
                    switch(evname)
                        case{'ROIMoved'}
                            app.position = evt.CurrentPosition;
                            app.TextArea_11.Value = mat2str(app.position);
                    end
                end
                
            app.ExportOriginalButton.Enable = 'on';
            app.ExportOriginalButton.Visible = 'on';
        end

        % Button pushed function: ExportOriginalButton
        function ExportOriginalButtonPushed(app, event)
            app.ExportOriginalButton.Color = 'y';
            
            stack_cut_exp = app.stack_cut;
            stack_cut_exp = permute(stack_cut_exp, [1 2 4 3]);
            
            raworiginal_name = 'original_cut.tiff';
            for K=1:size(stack_cut_exp,4)
               imwrite(stack_cut_exp(:, :, :, K), raworiginal_name, 'WriteMode', 'append');
            end
            
            thresh_cut_exp = app.threshstackcut;
            thresh_cut_exp(:,:,:,3) = zeros([size(thresh_cut_exp,1),size(thresh_cut_exp,2), size(thresh_cut_exp,3)]);

            thresh_cut_exp = permute(thresh_cut_exp, [1 2 4 3]);
            
            rawthresh_name = 'thresh_cut.tiff';
            
            for K=1:size(thresh_cut_exp,4)
               imwrite(thresh_cut_exp(:, :, :, K), rawthresh_name, 'WriteMode', 'append');
            end
            
            app.ExportOriginalButton.Color = 'g';
        end

        % Button down function: NucleiConnectivityButtonGroup
        function NucleiConnectivityButtonGroupButtonDown(app, event)
            if app.connectedButton.Value == 1
                app.connectivity = 6;
                app.TextArea_12.Value = num2str(app.connectivity);
            elseif app.connectedButton_2.Value == 1
                app.connectivity = 18;
                app.TextArea_12.Value = num2str(app.connectivity);
            elseif app.connectedButton_3.Value == 1
                app.connectivity = 26;
                app.TextArea_12.Value = num2str(app.connectivity);
            end
            
        end

        % Selection changed function: NucleiConnectivityButtonGroup
        function NucleiConnectivityButtonGroupSelectionChanged(app, event)

            if app.connectedButton.Value
                app.connectivity = 6;
            elseif app.connectedButton_2.Value
                app.connectivity = 18;
            elseif app.connectedButton_3.Value
                app.connectivity = 26;
            end
        end

        % Button pushed function: AnalyzeDataButton
        function AnalyzeDataButtonPushed(app, event)
            app.AnalyzeDataButton.BackgroundColor = 'y';
            
            %Making new directory with time     
                app.time = datetime('now');
                app.time = datestr(app.time);
                mkdir (app.time);
                cd (app.time);
                
                app.time = convertCharsToStrings(app.time);
           %Nuclei analysis
                app.parameters1 = [app.lowvol1, app.highvol1, app.lowextent1, app.highextent1];
                [app.nuccount, app.S1totaltable, app.labeledstack1, app.RGB_labeled] = nuclei_analysis_2(app, app.stack_cut, app.threshstackcut, app.parameters1, app.connectivity);
                
            %Table making
               if app.nuc_anal_num == 1
                   app.current_data = table(app.nuc_anal_num, app.time, app.nuccount, app.parameters1, 'VariableNames',{'Number', 'Time', 'Nuclei', 'Parameters [volmin,volmax,extmin,extmax]'});
               else
                   app.current_data(app.nuc_anal_num,:) = {app.nuc_anal_num, app.time, app.nuccount, app.parameters1};
               end
               app.UITable2.Data = app.current_data;
               
            
            %Raw Table Plotting
            app.UITable3.ColumnName = app.S1totaltable.Properties.VariableNames;
            app.UITable3.Data = app.S1totaltable;
            %Adjusting visibility
                app.ZvalueSlider_2.Enable = 'on';
                app.zslider_2.Enable = 'on';
                
            %Initialization plotting mosiac:
                %Resizing
                    size_x = size(app.RGB_labeled,2);
                    size_y = size(app.RGB_labeled,1);
                        app.Axes_RGB_3.XLim = [0 size_x];
                        app.Axes_RGB_3.YLim = [0 size_y];
                
                %showing
                imshow(app.RGB_labeled(:,:,:,app.zvalue_3), 'Parent', app.Axes_RGB_3);
                app.zslider_2.Value = num2str(app.zvalue_3);
                
                
           %Selecting rows in table
           app.z_values_col = app.S1totaltable.Centroid_3;
           
           %Adjusting 4th page variables
           if(app.nuc_anal_num == 1)
               app.AnalyzeDataButton_2.Enable = 'on';
               app.UseMeanNucleiVolumeCheckBox.Value = 1;
               app.UseMeanNucleiVolumeCheckBox.Enable = 'on';
               app.lowvol2 = app.mean_nuc_vol;
               app.LowerVolumeLimEditField_2.Enable = 'off';
               app.LowerVolumeLimEditField_2.Value = app.lowvol2;
               app.ExportOriginalButton.Enable = 'on';
               app.ExportNucleiButton.Enable = 'on';
           end
           
           %Increment Nuclei Analysis Number
               app.nuc_anal_num = app.nuc_anal_num + 1; 

              app.AnalyzeDataButton.BackgroundColor = 'g';
        end

        % Value changed function: LowerVolumeLimEditField
        function LowerVolumeLimEditFieldValueChanged(app, event)
            app.lowvol1 = app.LowerVolumeLimEditField.Value;
        end

        % Value changed function: UpperVolumeLimEditField
        function UpperVolumeLimEditFieldValueChanged(app, event)
            app.highvol1 = app.UpperVolumeLimEditField.Value;
        end

        % Value changed function: LowerExtentLimEditField
        function LowerExtentLimEditFieldValueChanged(app, event)
            app.lowextent1 = app.LowerExtentLimEditField.Value;           
        end

        % Value changed function: UpperExtentLimEditField
        function UpperExtentLimEditFieldValueChanged(app, event)
            app.highextent1 = app.UpperExtentLimEditField.Value;       
        end

        % Callback function
        function LowerVolumeLimEditField_2ValueChanged(app, event)

            
        end

        % Button pushed function: ChooseDirectoryButton
        function ChooseDirectoryButtonPushed(app, event)
           chosdir = uigetdir;
           cd (chosdir);
           app.AnalyzeDataButton.Enable = 'on';
           app.TextArea_24.Value = "Directory: " + chosdir;
           app.TextArea_25.Value = "Directory: " + chosdir;
        end

        % Button pushed function: SelectAreaofInterestButton
        function SelectAreaofInterestButtonPushed(app, event)
          app.SelectAreaofInterestButton.Enable = 'off';
          
          hold(app.Axes_ZYMaxProj_1,'on');
          app.roi = drawrectangle(app.Axes_ZYMaxProj_1);
          position = app.roi.Position;
          addlistener(app.roi,'ROIMoved',@allevents);
            function allevents(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'ROIMoved'}
                        position = evt.CurrentPosition;
                        
                        %Plot bonds on axes
                        ZYsize = size(app.RGBimagestackZY);
                        
                        %obtaining coordinates from draw rectangle
                        app.ymin = round(position(1));
                        app.zmin = round(position(2));
                        app.y2 = round(position(1)+position(3));
                        app.z2 = round(position(2)+position(4));
                            
                        %bound checking
                        if app.y2 > ZYsize(2)
                            app.y2 = ZYsize(2);
                        end
                        if app.z2 > ZYsize(1)
                            app.z2 = ZYsize(1);
                        end
                        
                        %Draw bounds on XY
                            app.RGBcopy = app.RGBimage;
                            app.RGBcopy(:,app.ymin,:) = 255;
                            app.RGBcopy(:,app.y2,:) = 255;
                            imshow(app.RGBcopy, 'Parent', app.Axes_RGB_1);
                            
                            app.todispcopy = app.stacktiff2disp_1;
                            app.todispcopy(:,app.ymin) = 255;
                            app.todispcopy(:,app.y2) = 255;
                            imshow(app.todispcopy, 'Parent', app.Axes_bounds_1);
                            
                        %Draw bounds on XZ
                            app.XZcopy = app.RGBimagestackXZ;
                            app.XZcopy(:,app.zmin,:) = 255;
                            app.XZcopy(:,app.z2,:) = 255;
                            imshow(app.XZcopy, 'Parent', app.Axes_XZMaxProj_1);
             
                end
            end
     
            %Initial Plotting
            
            %Plot bonds on axes
            ZYsize = size(app.RGBimagestackZY);
            
            %obtaining coordinates from draw rectangle
            app.ymin = round(position(1));
            app.zmin = round(position(2));
            app.y2 = round(position(1)+position(3));
            app.z2 = round(position(2)+position(4));
                
            %bound checking
            if app.y2 > ZYsize(2)
                app.y2 = ZYsize(2);
            end
            if app.z2 > ZYsize(1)
                app.z2 = ZYsize(1);
            end
            
            %Draw bounds on XY
                app.RGBcopy = app.RGBimage;
                app.RGBcopy(:,app.ymin,:) = 255;
                app.RGBcopy(:,app.y2,:) = 255;
                imshow(app.RGBcopy, 'Parent', app.Axes_RGB_1);
                
                app.todispcopy = app.stacktiff2disp_1;
                app.todispcopy(:,app.ymin) = 255;
                app.todispcopy(:,app.y2) = 255;
                imshow(app.todispcopy, 'Parent', app.Axes_bounds_1);
                
            %Draw bounds on XZ
                app.XZcopy = app.RGBimagestackXZ;
                app.XZcopy(:,app.zmin,:) = 255;
                app.XZcopy(:,app.z2,:) = 255;
                imshow(app.XZcopy, 'Parent', app.Axes_XZMaxProj_1);
             
                                
            %Set Confirm Area Button on
            app.ConfirmAreaButton.Enable = 'on';
        end

        % Button pushed function: ConfirmAreaButton
        function ConfirmAreaButtonPushed(app, event)
            %Clear rectangle
            delete(app.roi);
            hold(app.Axes_ZYMaxProj_1,'off');
            app.ClearSelectionButton_2.Enable = 'on';
            app.ContinueButton_pg1.Enable = 'on';
            %Cut stack
            app.stack_cut = app.stacktiff(:,app.ymin:app.y2,app.zmin:app.z2, :);
            app.TextArea_23.Value = "Selection Confirmed!" + newline + "Cut to: " + mat2str(size(app.stack_cut));
            app.ConfirmAreaButton.Enable = 'off';
        end

        % Value changed function: LowerBoundSpinner
        function LowerBoundSpinnerValueChanged(app, event)
            app.lowerthresh = app.LowerBoundSpinner.Value;
            
            %adjust limits of Upperbound spinner
                app.UpperBoundSpinner.Limits = [(app.lowerthresh+1), 255];
            
            %remake plot
            app.stacktiff2disp_1 = mat2gray(app.stacktiff(:,:,app.zvalue_1,app.layer_1), [app.lowerthresh, app.upperthresh]);
            app.todispcopy = app.stacktiff2disp_1;
            if ~isempty(app.ymin)
               app.todispcopy(:,app.ymin) = 255;
               app.todispcopy(:,app.y2) = 255; 
            end
            imshow(app.todispcopy, 'Parent', app.Axes_bounds_1);
            
        end

        % Value changed function: UpperBoundSpinner
        function UpperBoundSpinnerValueChanged(app, event)
            app.upperthresh = app.UpperBoundSpinner.Value;
            
            %adjust limits of Upperbound spinner
                app.LowerBoundSpinner.Limits = [1, (app.upperthresh-1)];
            
            %remake plot
            app.stacktiff2disp_1 = mat2gray(app.stacktiff(:,:,app.zvalue_1,app.layer_1), [app.lowerthresh, app.upperthresh]);
            app.todispcopy = app.stacktiff2disp_1;
            if ~isempty(app.ymin)
               app.todispcopy(:,app.ymin) = 255;
               app.todispcopy(:,app.y2) = 255; 
            end
            imshow(app.todispcopy, 'Parent', app.Axes_bounds_1);
            
        end

        % Button pushed function: ClearSelectionButton_2
        function ClearSelectionButton_2Pushed(app, event)
            app.TextArea_23.Value = "Selection Cleared!";
            app.SelectAreaofInterestButton.Enable = 'on';
            app.ContinueButton_pg1.Enable = 'off';
            app.ClearSelectionButton_2.Enable = 'off';
        end

        % Button pushed function: LoadDatasetButton_2
        function LoadDatasetButton_2Pushed(app, event)
           %Adjust visibility
           app.TotalThreshPointsSpinner.Enable = 'on';
           app.ConfirmTotalPointsButton.Enable = 'on';
           app.LoadDatasetButton_2.Enable = 'off';
           
           %Inital Calculations and initialization
            app.tot_cutz = size(app.stack_cut, 3);
            app.zvalue_2 = 1;
            app.compscale = 2;
            
            app.ScalingSpinner.Value = app.compscale;
            
            app.TextArea_4.Value = "Dataset Loaded!" + newline + "Please confirm total number of thresh points.";
                
        end

        % Button pushed function: ConfirmTotalPointsButton
        function ConfirmTotalPointsButtonPushed(app, event)
          %Adjust visibility
            app.TotalThreshPointsSpinner.Enable = 'off';
            app.ConfirmTotalPointsButton.Enable = 'off';
            app.ClearTotalPointsButton.Enable = 'on';
            app.StackLayerDropDown.Enable = 'on';
            app.SelectedPointSpinner.Enable = 'on';
            app.ConfirmThresholdButton.Enable = 'on';
            app.FitTypeDropDown.Enable = 'on';
            app.ConfirmandPlotButton.Enable = 'on';
            app.ThresholdSlider.Enable = 'on';
            app.FineThreshSlider.Enable = 'on';
            app.ScalingSpinner.Enable = 'on';
            
           %Adjust axes limits
           app.UIAxes4.XLim = [1, app.tot_cutz];
            
           %Initialization of points needed 
           app.pointneededR = 1;
           app.pointneededG = 1;
           
           app.numthreshval = app.TotalThreshPointsSpinner.Value;
           
           %Limits on selected point spinner
           app.SelectedPointSpinner.Limits = [1, app.numthreshval];
           
           %Setting zvalue vectors
           
               %zvaluevectR
               app.zvaluevectR = [1];
               valR = round(app.tot_cutz/(app.numthreshval - 1));
               
               for i = 1:(app.numthreshval - 1)
                   app.zvaluevectR = [app.zvaluevectR, (valR*i)];
               end
               if app.zvaluevectR(app.numthreshval) > app.tot_cutz
                   app.zvaluevectR(app.numthreshval) = app.tot_cutz;
               end
               
               %zvaluevectG
               app.zvaluevectG = app.zvaluevectR;
            
           %Initializing thresh vectors
               app.threshvectR = zeros(1, app.numthreshval);
               app.threshvectG = zeros(1, app.numthreshval);
            
           %Initializing Stack Layer
               app.stacknum = 1;
           
           %Initializing Points needed
            app.pointneededR = 1;
            app.pointneededG = 1;           

           %Initial Graphing
           
                %resizing
                    ysize = size(app.stack_cut, 1);
                    xsize = size(app.stack_cut, 2);

                    app.UIAxes2.XLim = [0 xsize];
                    app.UIAxes2.YLim = [0 ysize];
                    
                    app.UIAxes2_2.XLim = [0 xsize];
                    app.UIAxes2_2.YLim = [0 ysize];
                    
                    app.UIAxes2_3.XLim = [0 xsize];
                    app.UIAxes2_3.YLim = [0 ysize];
                    
                %RGB Image LEFT
                R = app.stack_cut(:,:,app.zvalue_2,1);
                G = app.stack_cut(:,:,app.zvalue_2,2);
                B = app.stack_cut(:,:,app.zvalue_2,3);
                
                RGBimage = cat(3, R, G, B);
                imshow(RGBimage, 'Parent', app.UIAxes2);

                %Thresholded image MIDDLE
                app.threshval = graythresh(app.stack_cut(:,:,app.zvalue_2, app.stacknum));
                threshlayer = imbinarize(app.stack_cut(:,:,app.zvalue_2, app.stacknum), app.threshval);
                
                    %Initialization of thresh slider
                    app.ThresholdSlider.Value = app.threshval;
                    app.threshvalcoarse = app.threshval;
                    app.threshvalfine = 0;
                    app.FineThreshSlider.Value = app.threshvalfine;
                    app.TextArea_7.Value = num2str(app.threshval);
                    
                imshow(threshlayer, 'Parent', app.UIAxes2_2);
                
                %Composite Image RIGHT
                app.compscale = 2; %initialization
                comp = uint8(zeros(size(app.stack_cut(:,:,app.zvalue_2,:))));
                comp = permute(comp, [1 2 4 3]);
                comp = comp(:,:,:,1);
                comp(:,:,1) = app.stack_cut(:,:,app.zvalue_2,app.stacknum)*app.compscale;
                comp(:,:,2) = threshlayer*255;
                
                imshow(comp, 'Parent', app.UIAxes2_3);                

         %Setting Table
         settable(app, app.zvaluevectR, app.threshvectR, app.zvaluevectG, app.threshvectG) 
        end

        % Value changed function: ScalingSpinner
        function ScalingSpinnerValueChanged(app, event)
            app.compscale = app.ScalingSpinner.Value;
            threshlayer = imbinarize(app.stack_cut(:,:,app.zvalue_2, app.stacknum), app.threshval);
          %Composite Image RIGHT
            comp = uint8(zeros(size(app.stack_cut(:,:,app.zvalue_2,:))));
            comp = permute(comp, [1 2 4 3]);
            comp = comp(:,:,:,1);
            comp(:,:,1) = app.stack_cut(:,:,app.zvalue_2,app.stacknum)*app.compscale;
            comp(:,:,2) = threshlayer*255;
            
            imshow(comp, 'Parent', app.UIAxes2_3);
        end

        % Callback function
        function UseNucleiAverageforActinVolumeMinCheckBoxValueChanged(app, event)
            app.actin_mean = app.UseNucleiAverageforActinVolumeMinCheckBox.Value;
            
            if app.actin_mean
                app.LowerVolumeLimEditField_2.Enable = "off";
            elseif ~app.actin_mean
                app.LowerVolumeLimEditField_2.Enable = "on";
            end
            
        end

        % Value changed function: ZvalueSlider_2
        function ZvalueSlider_2ValueChanged(app, event)
            app.zvalue_3 = round(app.tot_cutz*(app.ZvalueSlider_2.Value/100));
            
            %Reploting Image
            imshow(app.RGB_labeled(:,:,:,app.zvalue_3), 'Parent', app.Axes_RGB_3);
            app.zslider_2.Value = num2str(app.zvalue_3);
            
           %Selecting rows in table
           [minValue,app.tableindex] = min(abs(app.z_values_col-app.zvalue_3));
           
           %Scrolling
            scroll(app.UITable3,'Row',app.tableindex);
        end

        % Button pushed function: AnalyzeDataButton_2
        function AnalyzeDataButton_2Pushed(app, event)
            app.AnalyzeDataButton_2.BackgroundColor = 'y';
             
            %Actin analysis
                app.parameters1_actin = [app.lowvol2];
                [app.actin_count, app.S1totaltable_actin, app.labeledstack1_actin, app.RGB_labeled_actin] = actin_analysis(app, app.stack_cut, app.threshstackcut, app.parameters1_actin, app.connectivity_actin);
                
            %Table making
               if app.actin_anal_num == 1
                   app.current_data_actin = table(app.actin_anal_num, app.time, app.actin_count, app.parameters1_actin, 'VariableNames',{'Number', 'Time', 'Actin', 'Parameters [volmin]'});
               else
                   app.current_data_actin(app.actin_anal_num,:) = {app.actin_anal_num, app.time, app.actin_count, app.parameters1_actin};
               end
               app.UITable2_3.Data = app.current_data_actin;
               %Increment Actin Analysis Number
               app.actin_anal_num = app.actin_anal_num + 1; 
            
            %Raw Table Plotting
            app.UITable3_2.ColumnName = app.S1totaltable_actin.Properties.VariableNames;
            app.UITable3_2.Data = app.S1totaltable_actin;
            %Adjusting visibility
                app.ZvalueSlider_3.Enable = 'on';
                app.zslider_3.Enable = 'on';
                
            %Initialization plotting mosiac:
                %Resizing
                    size_x = size(app.RGB_labeled_actin,2);
                    size_y = size(app.RGB_labeled_actin,1);
                        app.Axes_RGB_4.XLim = [0 size_x];
                        app.Axes_RGB_4.YLim = [0 size_y];
                
                %showing
                imshow(app.RGB_labeled_actin(:,:,:,app.zvalue_3), 'Parent', app.Axes_RGB_4);
                app.zslider_3.Value = num2str(app.zvalue_3);
                
                
           %Selecting rows in table
           app.z_values_col_actin = app.S1totaltable_actin.Centroid_3;
           [minValue,app.tableindex_actin] = min(abs(app.z_values_col_actin-app.zvalue_3));
           
           app.ExportActinButton.Enable = 'on';
           
           app.AnalyzeDataButton_2.BackgroundColor = 'g';
        end

        % Selection changed function: ActinConnectivityButtonGroup
        function ActinConnectivityButtonGroupSelectionChanged(app, event)
            if app.connectedButton.Value
                app.connectivity_actin = 6;
            elseif app.connectedButton_2.Value
                app.connectivity_actin = 18;
            elseif app.connectedButton_3.Value
                app.connectivity_actin = 26;
            end
            
        end

        % Value changed function: ZvalueSlider_3
        function ZvalueSlider_3ValueChanged(app, event)
            app.zvalue_4 = round(app.tot_cutz*(app.ZvalueSlider_3.Value/100));
            
            %Reploting Image
            imshow(app.RGB_labeled_actin(:,:,:,app.zvalue_4), 'Parent', app.Axes_RGB_4);
            app.zslider_3.Value = num2str(app.zvalue_4);
           
           %Selecting rows in table
           [minValue,app.tableindex_actin] = min(abs(app.z_values_col_actin-app.zvalue_4));
           
           %Scrolling
            scroll(app.UITable3_2,'Row',app.tableindex_actin);
        end

        % Value changed function: UseMeanNucleiVolumeCheckBox
        function UseMeanNucleiVolumeCheckBoxValueChanged(app, event)
            value = app.UseMeanNucleiVolumeCheckBox.Value;
                
            if value == 1
               app.lowvol2 = app.mean_nuc_vol;
               app.LowerVolumeLimEditField_2.Enable = 'off';
               app.LowerVolumeLimEditField_2.Value = app.lowvol2;
            elseif value == 0
               app.LowerVolumeLimEditField_2.Enable = 'on';
               app.LowerVolumeLimEditField_2.Value = app.lowvol2;
            end
        end

        % Button pushed function: ExportNucleiButton
        function ExportNucleiButtonPushed(app, event)
             app.ExportNucleiButton.Color = 'y';
             
            outputFileName_tiff = "labeled_nuclei.tiff";
            outputTableName = "nuclei_labeled.xls";
            
            labeledstack_export = app.labeledstack1;

            for K=1:size(labeledstack_export,4)
               imwrite(labeledstack_export(:, :, :, K), outputFileName_tiff, 'WriteMode', 'append');
            end
            
            writetable(app.S1totaltable, outputTableName);
            
            app.ExportNucleiButton.Color = 'g';

        end

        % Button pushed function: ExportActinButton
        function ExportActinButtonPushed(app, event)
           app.ConfirmandPlotButton.BackgroundColor = 'y';
            outputFileName_tiff = "labeled_actin.tiff";
            outputTableName = "actin_labeled.xls";            
            
            labeledstack_export = app.labeledstack1_actin;
            
            for K=1:size(labeledstack_export,4)
               imwrite(labeledstack_export(:, :, :, K), outputFileName_tiff, 'WriteMode', 'append');
            end
            
            writetable(app.S1totaltable, outputTableName);
            
            app.ConfirmandPlotButton.BackgroundColor = 'g';
        end

        % Button pushed function: ClearTotalPointsButton
        function ClearTotalPointsButtonPushed(app, event)
            %Adjust visibility
            app.TotalThreshPointsSpinner.Enable = 'on';
            app.ConfirmTotalPointsButton.Enable = 'on';
            app.ClearTotalPointsButton.Enable = 'off';
            app.StackLayerDropDown.Enable = 'off';
            app.SelectedPointSpinner.Enable = 'off';
            app.ConfirmThresholdButton.Enable = 'off';
            app.FitTypeDropDown.Enable = 'off';
            app.ConfirmandPlotButton.Enable = 'off';
            app.ThresholdSlider.Enable = 'off';
            app.FineThreshSlider.Enable = 'off';
            app.ScalingSpinner.Enable = 'off';
            
           %Adjust axes limits
           app.UIAxes4.XLim = [1, app.tot_cutz];
            
           %Initialization of points needed 
           app.pointneededR = 1;
           app.pointneededG = 1;
           
           app.numthreshval = app.TotalThreshPointsSpinner.Value;
           
           %Limits on selected point spinner
           app.SelectedPointSpinner.Limits = [1, app.numthreshval];
           
           %Setting zvalue vectors
           
               %zvaluevectR
               app.zvaluevectR = [1];
               valR = round(app.tot_cutz/(app.numthreshval - 1));
               
               for i = 1:(app.numthreshval - 1)
                   app.zvaluevectR = [app.zvaluevectR, (valR*i)];
               end
               if app.zvaluevectR(app.numthreshval) > app.tot_cutz
                   app.zvaluevectR(app.numthreshval) = app.tot_cutz;
               end
               
               %zvaluevectG
               app.zvaluevectG = app.zvaluevectR;
            
           %Initializing thresh vectors
               app.threshvectR = zeros(1, app.numthreshval);
               app.threshvectG = zeros(1, app.numthreshval);
            
           %Initializing Stack Layer
               app.stacknum = 1;
           
           %Initializing Points needed
            app.pointneededR = 1;
            app.pointneededG = 1;           

           %Initial Graphing
           
                %resizing
                    ysize = size(app.stack_cut, 1);
                    xsize = size(app.stack_cut, 2);

                    app.UIAxes2.XLim = [0 xsize];
                    app.UIAxes2.YLim = [0 ysize];
                    
                    app.UIAxes2_2.XLim = [0 xsize];
                    app.UIAxes2_2.YLim = [0 ysize];
                    
                    app.UIAxes2_3.XLim = [0 xsize];
                    app.UIAxes2_3.YLim = [0 ysize];
                    
                %RGB Image LEFT
                R = app.stack_cut(:,:,app.zvalue_2,1);
                G = app.stack_cut(:,:,app.zvalue_2,2);
                B = app.stack_cut(:,:,app.zvalue_2,3);
                
                RGBimage = cat(3, R, G, B);
                imshow(RGBimage, 'Parent', app.UIAxes2);

                %Thresholded image MIDDLE
                app.threshval = graythresh(app.stack_cut(:,:,app.zvalue_2, app.stacknum));
                threshlayer = imbinarize(app.stack_cut(:,:,app.zvalue_2, app.stacknum), app.threshval);
                
                    %Initialization of thresh slider
                    app.ThresholdSlider.Value = app.threshval;
                    app.threshvalcoarse = app.threshval;
                    app.threshvalfine = 0;
                    app.FineThreshSlider.Value = app.threshvalfine;
                    app.TextArea_7.Value = num2str(app.threshval);
                    
                imshow(threshlayer, 'Parent', app.UIAxes2_2);
                
                %Composite Image RIGHT
                app.compscale = 2; %initialization
                comp = uint8(zeros(size(app.stack_cut(:,:,app.zvalue_2,:))));
                comp = permute(comp, [1 2 4 3]);
                comp = comp(:,:,:,1);
                comp(:,:,1) = app.stack_cut(:,:,app.zvalue_2,app.stacknum)*app.compscale;
                comp(:,:,2) = threshlayer*255;
                
                imshow(comp, 'Parent', app.UIAxes2_3);                

         %Setting Table
         settable(app, app.zvaluevectR, app.threshvectR, app.zvaluevectG, app.threshvectG) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BoundsTab = uitab(app.TabGroup);
            app.BoundsTab.Title = 'Bounds';
            app.XYProjectionsPanel = uipanel(app.BoundsTab);
            app.XYProjectionsPanel.Title = 'XY Projections';
            app.Axes_RGB_1 = uiaxes(app.XYProjectionsPanel);
            app.Axes_RGB_1.XTick = [];
            app.Axes_RGB_1.YTick = [];
            app.Axes_RGB_1.ButtonDownFcn = createCallbackFcn(app, @Axes_RGB_1ButtonDown, true);
            app.Axes_bounds_1 = uiaxes(app.XYProjectionsPanel);
            app.Axes_bounds_1.XTick = [];
            app.Axes_bounds_1.YTick = [];
            app.LoadDataSetButton = uibutton(app.BoundsTab, 'push');
            app.LoadDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataSetButtonPushed, true);
            app.LoadDataSetButton.Text = 'Load Data Set';
            app.File_text_1 = uitextarea(app.BoundsTab);
            app.File_text_1.ValueChangedFcn = createCallbackFcn(app, @File_text_1ValueChanged, true);
            app.File_text_1.Editable = 'off';
            app.ZvalueSliderLabel = uilabel(app.BoundsTab);
            app.ZvalueSliderLabel.Text = 'Z value';
            app.ZvalueSlider = uislider(app.BoundsTab);
            app.ZvalueSlider.Limits = [1 100];
            app.ZvalueSlider.MajorTicks = [];
            app.ZvalueSlider.ValueChangedFcn = createCallbackFcn(app, @ZvalueSliderValueChanged, true);
            app.ZvalueSlider.MinorTicks = [];
            app.ZvalueSlider.Value = 1;
            app.zslider_1 = uitextarea(app.BoundsTab);
            app.zslider_1.ValueChangedFcn = createCallbackFcn(app, @zslider_1ValueChanged, true);
            app.zslider_1.Editable = 'off';
            app.overhead_1 = uitextarea(app.BoundsTab);
            app.overhead_1.Editable = 'off';
            app.XZViewYMaximumProjectionPanel = uipanel(app.BoundsTab);
            app.XZViewYMaximumProjectionPanel.Title = 'XZ View (Y Maximum Projection)';
            app.Axes_XZMaxProj_1 = uiaxes(app.XZViewYMaximumProjectionPanel);
            app.Axes_XZMaxProj_1.XTick = [];
            app.Axes_XZMaxProj_1.YTick = [];
            app.ScalingPanel = uipanel(app.BoundsTab);
            app.ScalingPanel.Title = 'Scaling';
            app.LowerBoundSpinnerLabel = uilabel(app.ScalingPanel);
            app.LowerBoundSpinnerLabel.Text = 'Lower Bound';
            app.LowerBoundSpinner = uispinner(app.ScalingPanel);
            app.LowerBoundSpinner.ValueChangedFcn = createCallbackFcn(app, @LowerBoundSpinnerValueChanged, true);
            app.UpperBoundSpinnerLabel = uilabel(app.ScalingPanel);
            app.UpperBoundSpinnerLabel.Text = 'Upper Bound';
            app.UpperBoundSpinner = uispinner(app.ScalingPanel);
            app.UpperBoundSpinner.ValueChangedFcn = createCallbackFcn(app, @UpperBoundSpinnerValueChanged, true);
            app.ZYViewXMaximumProjectionPanel = uipanel(app.BoundsTab);
            app.ZYViewXMaximumProjectionPanel.Title = 'ZY View (X Maximum Projection)';
            app.Axes_ZYMaxProj_1 = uiaxes(app.ZYViewXMaximumProjectionPanel);
            app.Axes_ZYMaxProj_1.XTick = [];
            app.Axes_ZYMaxProj_1.YTick = [];
            app.Axes_ZYMaxProj_1.ButtonDownFcn = createCallbackFcn(app, @Axes_ZYMaxProj_1ButtonDown, true);
            app.SelectAreaofInterestButton = uibutton(app.BoundsTab, 'push');
            app.SelectAreaofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAreaofInterestButtonPushed, true);
            app.SelectAreaofInterestButton.Text = 'Select Area of Interest';
            app.ConfirmAreaButton = uibutton(app.BoundsTab, 'push');
            app.ConfirmAreaButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmAreaButtonPushed, true);
            app.ConfirmAreaButton.Text = 'Confirm Area';
            app.ClearSelectionButton_2 = uibutton(app.BoundsTab, 'push');
            app.ClearSelectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearSelectionButton_2Pushed, true);
            app.ClearSelectionButton_2.Text = 'Clear Selection';
            app.TextArea_23 = uitextarea(app.BoundsTab);
            app.ContinueButton_pg1 = uibutton(app.BoundsTab, 'push');
            app.ContinueButton_pg1.Text = 'Continue';
            app.SelectNewDataSetButton = uibutton(app.BoundsTab, 'push');
            app.SelectNewDataSetButton.Text = 'Select New Data Set';
            app.ThresholdTab = uitab(app.TabGroup);
            app.ThresholdTab.Title = 'Threshold';
            app.ThresholdTab.ButtonDownFcn = createCallbackFcn(app, @ThresholdTabButtonDown, true);
            app.StackLayerDropDownLabel = uilabel(app.ThresholdTab);
            app.StackLayerDropDownLabel.Text = 'Stack Layer';
            app.StackLayerDropDown = uidropdown(app.ThresholdTab);
            app.StackLayerDropDown.Items = {'Nuclei', 'Cyto'};
            app.StackLayerDropDown.ValueChangedFcn = createCallbackFcn(app, @StackLayerDropDownValueChanged, true);
            app.StackLayerDropDown.Value = 'Nuclei';
            app.ConfirmThresholdButton = uibutton(app.ThresholdTab, 'push');
            app.ConfirmThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmThresholdButtonPushed, true);
            app.ConfirmThresholdButton.Text = 'Confirm Threshold';
            app.TextArea_4 = uitextarea(app.ThresholdTab);
            app.ThresholdConfirmationPanel = uipanel(app.ThresholdTab);
            app.ThresholdConfirmationPanel.Title = 'Threshold Confirmation';
            app.UIAxes4 = uiaxes(app.ThresholdConfirmationPanel);
            title(app.UIAxes4, 'Extrapolated Threshold')
            xlabel(app.UIAxes4, 'Z Value')
            ylabel(app.UIAxes4, 'Threshold Value')
            zlabel(app.UIAxes4, 'Z')
            app.TextArea_6 = uitextarea(app.ThresholdConfirmationPanel);
            app.FitTypeDropDownLabel = uilabel(app.ThresholdConfirmationPanel);
            app.FitTypeDropDownLabel.Text = 'Fit Type';
            app.FitTypeDropDown = uidropdown(app.ThresholdConfirmationPanel);
            app.FitTypeDropDown.Items = {'Linear'};
            app.FitTypeDropDown.Value = 'Linear';
            app.ConfirmandPlotButton = uibutton(app.ThresholdConfirmationPanel, 'push');
            app.ConfirmandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmandPlotButtonPushed, true);
            app.ConfirmandPlotButton.Text = 'Confirm and Plot';
            app.TotalThreshPointsSpinnerLabel = uilabel(app.ThresholdTab);
            app.TotalThreshPointsSpinnerLabel.Text = 'Total Thresh Points';
            app.TotalThreshPointsSpinner = uispinner(app.ThresholdTab);
            app.TotalThreshPointsSpinner.Limits = [3 10];
            app.TotalThreshPointsSpinner.ValueChangedFcn = createCallbackFcn(app, @TotalThreshPointsSpinnerValueChanged, true);
            app.TotalThreshPointsSpinner.Value = 3;
            app.SelectedPointSpinnerLabel = uilabel(app.ThresholdTab);
            app.SelectedPointSpinnerLabel.Text = 'Selected Point';
            app.SelectedPointSpinner = uispinner(app.ThresholdTab);
            app.SelectedPointSpinner.Limits = [1 10];
            app.SelectedPointSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectedPointSpinnerValueChanged, true);
            app.SelectedPointSpinner.Value = 1;
            app.UITable = uitable(app.ThresholdTab);
            app.UITable.ColumnName = {'Z Val (Layer 1)'; 'Layer 1 Thresh'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.ThresholdTab);
            app.UITable_2.ColumnName = {'Z Val (Layer 2)'; 'Layer 2 Thresh'};
            app.UITable_2.RowName = {};
            app.OriginalRGBThresholdedImageOverlayImagePanel = uipanel(app.ThresholdTab);
            app.OriginalRGBThresholdedImageOverlayImagePanel.Title = 'Original (RGB) || Thresholded Image || Overlay Image';
            app.UIAxes2 = uiaxes(app.OriginalRGBThresholdedImageOverlayImagePanel);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.OriginalRGBThresholdedImageOverlayImagePanel);
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_3 = uiaxes(app.OriginalRGBThresholdedImageOverlayImagePanel);
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.ThresholdAdjustmentScalingFactorPanel = uipanel(app.ThresholdTab);
            app.ThresholdAdjustmentScalingFactorPanel.Title = 'Threshold Adjustment || Scaling Factor';
            app.ThresholdSlider = uislider(app.ThresholdAdjustmentScalingFactorPanel);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSliderLabel = uilabel(app.ThresholdAdjustmentScalingFactorPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.FineThreshSlider = uislider(app.ThresholdAdjustmentScalingFactorPanel);
            app.FineThreshSlider.Limits = [-0.1 0.1];
            app.FineThreshSlider.MajorTicks = [];
            app.FineThreshSlider.ValueChangedFcn = createCallbackFcn(app, @FineThreshSliderValueChanged, true);
            app.FineThreshSlider.MinorTicks = [];
            app.FineThreshSliderLabel = uilabel(app.ThresholdAdjustmentScalingFactorPanel);
            app.FineThreshSliderLabel.Text = 'Fine Thresh';
            app.ScalingSpinnerLabel = uilabel(app.ThresholdAdjustmentScalingFactorPanel);
            app.ScalingSpinnerLabel.Text = 'Scaling';
            app.ScalingSpinner = uispinner(app.ThresholdAdjustmentScalingFactorPanel);
            app.ScalingSpinner.Step = 0.01;
            app.ScalingSpinner.Limits = [0.01 8];
            app.ScalingSpinner.ValueChangedFcn = createCallbackFcn(app, @ScalingSpinnerValueChanged, true);
            app.ScalingSpinner.Value = 0.01;
            app.TextArea_7 = uitextarea(app.ThresholdAdjustmentScalingFactorPanel);
            app.TextArea_7.Editable = 'off';
            app.LoadDatasetButton_2 = uibutton(app.ThresholdTab, 'push');
            app.LoadDatasetButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetButton_2Pushed, true);
            app.LoadDatasetButton_2.Text = 'Load Dataset';
            app.ConfirmTotalPointsButton = uibutton(app.ThresholdTab, 'push');
            app.ConfirmTotalPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmTotalPointsButtonPushed, true);
            app.ConfirmTotalPointsButton.Text = 'Confirm Total Points';
            app.ClearTotalPointsButton = uibutton(app.ThresholdTab, 'push');
            app.ClearTotalPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTotalPointsButtonPushed, true);
            app.ClearTotalPointsButton.Text = 'Clear Total Points';
            app.NucleiAnalysisTab = uitab(app.TabGroup);
            app.NucleiAnalysisTab.Title = 'Nuclei Analysis';
            app.Axes_RGB_3 = uiaxes(app.NucleiAnalysisTab);
            app.Axes_RGB_3.XTick = [];
            app.Axes_RGB_3.YTick = [];
            app.ExportOriginalButton = uibutton(app.NucleiAnalysisTab, 'push');
            app.ExportOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ExportOriginalButtonPushed, true);
            app.ExportOriginalButton.Text = 'Export Original';
            app.NucleiConnectivityButtonGroup = uibuttongroup(app.NucleiAnalysisTab);
            app.NucleiConnectivityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NucleiConnectivityButtonGroupSelectionChanged, true);
            app.NucleiConnectivityButtonGroup.Title = 'Nuclei Connectivity';
            app.NucleiConnectivityButtonGroup.ButtonDownFcn = createCallbackFcn(app, @NucleiConnectivityButtonGroupButtonDown, true);
            app.connectedButton = uitogglebutton(app.NucleiConnectivityButtonGroup);
            app.connectedButton.Text = '6-connected';
            app.connectedButton.Value = true;
            app.connectedButton_2 = uitogglebutton(app.NucleiConnectivityButtonGroup);
            app.connectedButton_2.Text = '18-connected';
            app.connectedButton_3 = uitogglebutton(app.NucleiConnectivityButtonGroup);
            app.connectedButton_3.Text = '26-connected';
            app.AnalyzeDataButton = uibutton(app.NucleiAnalysisTab, 'push');
            app.AnalyzeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeDataButtonPushed, true);
            app.AnalyzeDataButton.Text = 'Analyze Data';
            app.NucleiFilteringParametersPanel = uipanel(app.NucleiAnalysisTab);
            app.NucleiFilteringParametersPanel.Title = 'Nuclei Filtering Parameters';
            app.LowerVolumeLimEditFieldLabel = uilabel(app.NucleiFilteringParametersPanel);
            app.LowerVolumeLimEditFieldLabel.Text = 'Lower Volume Lim';
            app.LowerVolumeLimEditField = uieditfield(app.NucleiFilteringParametersPanel, 'numeric');
            app.LowerVolumeLimEditField.ValueChangedFcn = createCallbackFcn(app, @LowerVolumeLimEditFieldValueChanged, true);
            app.UpperVolumeLimEditFieldLabel = uilabel(app.NucleiFilteringParametersPanel);
            app.UpperVolumeLimEditFieldLabel.Text = 'Upper Volume Lim';
            app.UpperVolumeLimEditField = uieditfield(app.NucleiFilteringParametersPanel, 'numeric');
            app.UpperVolumeLimEditField.ValueChangedFcn = createCallbackFcn(app, @UpperVolumeLimEditFieldValueChanged, true);
            app.LowerExtentLimEditFieldLabel = uilabel(app.NucleiFilteringParametersPanel);
            app.LowerExtentLimEditFieldLabel.Text = 'Lower Extent Lim';
            app.LowerExtentLimEditField = uieditfield(app.NucleiFilteringParametersPanel, 'numeric');
            app.LowerExtentLimEditField.ValueChangedFcn = createCallbackFcn(app, @LowerExtentLimEditFieldValueChanged, true);
            app.UpperExtentLimEditFieldLabel = uilabel(app.NucleiFilteringParametersPanel);
            app.UpperExtentLimEditFieldLabel.Text = 'Upper Extent Lim';
            app.UpperExtentLimEditField = uieditfield(app.NucleiFilteringParametersPanel, 'numeric');
            app.UpperExtentLimEditField.ValueChangedFcn = createCallbackFcn(app, @UpperExtentLimEditFieldValueChanged, true);
            app.ChooseDirectoryButton = uibutton(app.NucleiAnalysisTab, 'push');
            app.ChooseDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDirectoryButtonPushed, true);
            app.ChooseDirectoryButton.Text = 'Choose Directory';
            app.UITable2 = uitable(app.NucleiAnalysisTab);
            app.UITable2.ColumnName = {'Number'; 'Time'; 'Nuclei'; 'Parameters'};
            app.UITable2.RowName = {};
            app.FilteredTablePanel = uipanel(app.NucleiAnalysisTab);
            app.FilteredTablePanel.Title = 'Filtered Table';
            app.UITable3 = uitable(app.FilteredTablePanel);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.AnalysisSummaryPanel = uipanel(app.NucleiAnalysisTab);
            app.AnalysisSummaryPanel.Title = 'Analysis Summary';
            app.UIAxes5 = uiaxes(app.AnalysisSummaryPanel);
            title(app.UIAxes5, 'Volume')
            ylabel(app.UIAxes5, 'Number')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5_2 = uiaxes(app.AnalysisSummaryPanel);
            title(app.UIAxes5_2, 'Extent')
            ylabel(app.UIAxes5_2, 'Number')
            zlabel(app.UIAxes5_2, 'Z')
            app.UITable2_2 = uitable(app.AnalysisSummaryPanel);
            app.UITable2_2.ColumnName = '';
            app.UITable2_2.RowName = {};
            app.TextArea_24 = uitextarea(app.NucleiAnalysisTab);
            app.ZvalueSlider_2Label = uilabel(app.NucleiAnalysisTab);
            app.ZvalueSlider_2Label.Text = 'Z value';
            app.ZvalueSlider_2 = uislider(app.NucleiAnalysisTab);
            app.ZvalueSlider_2.Limits = [1 100];
            app.ZvalueSlider_2.MajorTicks = [];
            app.ZvalueSlider_2.ValueChangedFcn = createCallbackFcn(app, @ZvalueSlider_2ValueChanged, true);
            app.ZvalueSlider_2.MinorTicks = [];
            app.ZvalueSlider_2.Value = 1;
            app.zslider_2 = uitextarea(app.NucleiAnalysisTab);
            app.zslider_2.Editable = 'off';
            app.ExportNucleiButton = uibutton(app.NucleiAnalysisTab, 'push');
            app.ExportNucleiButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNucleiButtonPushed, true);
            app.ExportNucleiButton.Text = 'Export - Nuclei';
            app.ActinAnalysisTab = uitab(app.TabGroup);
            app.ActinAnalysisTab.Title = 'Actin Analysis';
            app.Axes_RGB_4 = uiaxes(app.ActinAnalysisTab);
            app.Axes_RGB_4.XTick = [];
            app.Axes_RGB_4.YTick = [];
            app.ExportActinButton = uibutton(app.ActinAnalysisTab, 'push');
            app.ExportActinButton.ButtonPushedFcn = createCallbackFcn(app, @ExportActinButtonPushed, true);
            app.ExportActinButton.Text = 'Export - Actin';
            app.ActinConnectivityButtonGroup = uibuttongroup(app.ActinAnalysisTab);
            app.ActinConnectivityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ActinConnectivityButtonGroupSelectionChanged, true);
            app.ActinConnectivityButtonGroup.Title = 'Actin Connectivity';
            app.connectedButton_4 = uitogglebutton(app.ActinConnectivityButtonGroup);
            app.connectedButton_4.Text = '6-connected';
            app.connectedButton_4.Value = true;
            app.connectedButton_5 = uitogglebutton(app.ActinConnectivityButtonGroup);
            app.connectedButton_5.Text = '18-connected';
            app.connectedButton_6 = uitogglebutton(app.ActinConnectivityButtonGroup);
            app.connectedButton_6.Text = '26-connected';
            app.AnalyzeDataButton_2 = uibutton(app.ActinAnalysisTab, 'push');
            app.AnalyzeDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeDataButton_2Pushed, true);
            app.AnalyzeDataButton_2.Text = 'Analyze Data';
            app.ActinFilteringParametersPanel = uipanel(app.ActinAnalysisTab);
            app.ActinFilteringParametersPanel.Title = 'Actin Filtering Parameters';
            app.LowerVolumeLimEditField_2Label = uilabel(app.ActinFilteringParametersPanel);
            app.LowerVolumeLimEditField_2Label.Text = 'Lower Volume Lim';
            app.LowerVolumeLimEditField_2 = uieditfield(app.ActinFilteringParametersPanel, 'numeric');
            app.UseMeanNucleiVolumeCheckBox = uicheckbox(app.ActinFilteringParametersPanel);
            app.UseMeanNucleiVolumeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseMeanNucleiVolumeCheckBoxValueChanged, true);
            app.UseMeanNucleiVolumeCheckBox.Text = 'Use Mean Nuclei Volume';
            app.UITable2_3 = uitable(app.ActinAnalysisTab);
            app.UITable2_3.ColumnName = {'Number'; 'Time'; 'Nuclei'; 'Parameters'};
            app.UITable2_3.RowName = {};
            app.FilteredTablePanel_2 = uipanel(app.ActinAnalysisTab);
            app.FilteredTablePanel_2.Title = 'Filtered Table';
            app.UITable3_2 = uitable(app.FilteredTablePanel_2);
            app.UITable3_2.ColumnName = '';
            app.UITable3_2.RowName = {};
            app.AnalysisSummaryPanel_2 = uipanel(app.ActinAnalysisTab);
            app.AnalysisSummaryPanel_2.Title = 'Analysis Summary';
            app.UIAxes5_3 = uiaxes(app.AnalysisSummaryPanel_2);
            title(app.UIAxes5_3, 'Volume')
            ylabel(app.UIAxes5_3, 'Number')
            zlabel(app.UIAxes5_3, 'Z')
            app.UIAxes5_4 = uiaxes(app.AnalysisSummaryPanel_2);
            title(app.UIAxes5_4, 'Extent')
            ylabel(app.UIAxes5_4, 'Number')
            zlabel(app.UIAxes5_4, 'Z')
            app.UITable2_4 = uitable(app.AnalysisSummaryPanel_2);
            app.UITable2_4.ColumnName = '';
            app.UITable2_4.RowName = {};
            app.TextArea_25 = uitextarea(app.ActinAnalysisTab);
            app.ZvalueSlider_3Label = uilabel(app.ActinAnalysisTab);
            app.ZvalueSlider_3Label.Text = 'Z value';
            app.ZvalueSlider_3 = uislider(app.ActinAnalysisTab);
            app.ZvalueSlider_3.Limits = [1 100];
            app.ZvalueSlider_3.MajorTicks = [];
            app.ZvalueSlider_3.ValueChangedFcn = createCallbackFcn(app, @ZvalueSlider_3ValueChanged, true);
            app.ZvalueSlider_3.MinorTicks = [];
            app.ZvalueSlider_3.Value = 1;
            app.zslider_3 = uitextarea(app.ActinAnalysisTab);
            app.zslider_3.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sprout_version_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end